classdef AbrirInforme < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NombredearchivoEditFieldLabel  matlab.ui.control.Label
        NombredearchivoEditField       matlab.ui.control.EditField
        Button_4                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        DropDown                       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        VentanaInforme; % Description
        Nombre="NO NAME";
        Fecha;
        Observacion=[];
        TiempoReal;
        TiempoIdeal;
        YawHombroIdeal=[];
        PitchHombroIdeal=[];
        RollCodoIdeal=[];
        PitchCodoIdeal=[];
        PitchHombro=[];
        PitchCodo=[];
        YawHombro=[];
        RollCodo=[];
        
        Edad=[];
        Valslidec=[];
        
        
        CalMax=[];
        CalMin=[];
        TiempoEMG=[];
        FinalSignal=[];
        Umbral=[];
        
    end
    
    methods (Access = public)
       %__________________ 
        %OPCIÓN SIMULACIÓN
        function  AsignarAlInforme(app)
       %Importaremos los datos del archivo.mat(para utilizar estos en la funcionfunc() que 
       %llamará  a informeventana y colocará estos datos en las correspondtes casillas).
           
            %1)Importamos el archivo
            Datos=importdata(app.NombredearchivoEditField.Value);
            %2)Asignamos variables del archivo a variables globales de esta ventana 
            app.Nombre=Datos.Paciente.Nombre;
            app.Edad=Datos.Paciente.Edad;
            app.Fecha=Datos.Paciente.Fecha;
            app.YawHombro=Datos.Paciente.Hombro.YawHombro;
            app.PitchHombro=Datos.Paciente.Hombro.PitchHombro;
            app.RollHombro=Datos.Paciente.Hombro.RollHombro;
            app.YawCodo=Datos.Paciente.Codo.YawCodo;
            app.RollCodo=Datos.Paciente.Codo.RollCodo;
            app.Valslidec=Datos.Paciente.ValorSlider;
            
        end
        %__________________
         %OPCIÓN EMG
            function  AsignarAlInformeEMG(app)
       %Importaremos los datos del archivo.mat(para utilizar estos en la funcionfunc() que 
       %llamará  a Ventana informeEMG y colocará estos datos en las correspondtes casillas).
           
            %1)Importamos el archivo
            Datos=importdata(app.NombredearchivoEditField.Value);
            %2)Asignamos variables del archivo a variables globales de esta ventana 
            app.Nombre=Datos.Paciente.Nombre;
            app.Edad=Datos.Paciente.Edad;
            app.Fecha=Datos.Paciente.Fecha;
            app.CalMax=Datos.Paciente.ActivationMuscular.Maximum;
            app.CalMin=Datos.Paciente.ActivationMuscular.Reposo;
            app.Umbral=Datos.Paciente.ActivationMuscular.UmbralActivation;
            app.TiempoEMG=Datos.Paciente.Tiempo;
            app.FinalSignal=Datos.Paciente.SenalFinal;


            end    
            
        function AsingarAlInformeMarcadores(app)
               %1)Importamos el archivo
            Datos=importdata(app.NombredearchivoEditField.Value);
            %2)Asignamos variables del archivo a variables globales de esta ventana 
            app.Nombre=Datos.Paciente.Nombre;
            app.Edad=Datos.Paciente.Edad;
            app.Fecha=Datos.Paciente.Fecha;
            app.Observacion=Datos.Paciente.Observacion;
            app.TiempoReal=Datos.Paciente.TiempoReal;
            app.TiempoIdeal=Datos.Paciente.TiempoIdeal;
            app.PitchHombroIdeal=Datos.Paciente.Hombro.PitchHombroIdeal;
            app.YawHombroIdeal=Datos.Paciente.Hombro.YawHombroIdeal;
            app.RollCodoIdeal=Datos.Paciente.Codo.RollCodoIdeal;
            app.PitchCodoIdeal=Datos.Paciente.Codo.PitchCodoIdeal;
            app.PitchHombro=Datos.Paciente.Hombro.PitchHombro;
            app.YawHombro=Datos.Paciente.Hombro.YawHombro;
            app.RollCodo=Datos.Paciente.Codo.RollCodo;
            app.PitchCodo=Datos.Paciente.Codo.PitchCodo;
            app.Valslidec=Datos.Paciente.ValorSlider;
        end
        
        function  TipoInforme(app,tipoInforme)
            %Recibe un valor " " y lo establece como valor de la app.DropDown
            app.DropDown.Value=tipoInforme;
        end         


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
       %__________________ 
        %OPCIÓN SIMULACIÓN 
            if app.DropDown.Value == "Simulación"
            app.VentanaInforme=VentanaInforme16NOV;
            AsignarAlInforme(app);
            func(app.VentanaInforme,app.Nombre,app.Edad,app.YawHombro,app.PitchHombro,app.RollHombro,app.YawCodo,app.RollCodo,app.Valslidec) ;
            aceptaFecha(app.VentanaInforme,app.Fecha);
            graficaInforme(app.VentanaInforme);
            index=round(app.Valslidec);
                if index ~=0
                setSlider(app.VentanaInforme,index);
                dibujaENinforme(app.VentanaInforme,index);
                end


            elseif app.DropDown.Value == "Electromiograma"
            app.VentanaInforme=InformeEMG;
            AsignarAlInformeEMG(app);
            func(app.VentanaInforme,app.Nombre,app.Edad, app.CalMax,app.CalMin, app.Umbral, app.TiempoEMG,app.FinalSignal);
            graficaEMG(app.VentanaInforme);
            aceptaFecha(app.VentanaInforme,app.Fecha);
            
            elseif app.DropDown.Value == "Marcadores"
                app.VentanaInforme = VentanaInformeMarcadores;
                AsingarAlInformeMarcadores(app);
                func(app.VentanaInforme,app.Nombre,app.Edad,app.PitchHombroIdeal,app.YawHombroIdeal,app.RollCodoIdeal,app.PitchCodoIdeal,app.PitchHombro,app.YawHombro,app.RollCodo,app.PitchCodo,app.Valslidec);
                aceptaFecha(app.VentanaInforme,app.Fecha,app.Observacion,app.TiempoReal,app.TiempoIdeal);
                graficaMarcadores(app.VentanaInforme);%Las funciones de informeventana son public por eso se pueden usar.
                index=round(app.Valslidec);
                if index ~=0
                setSlider(app.VentanaInforme,index);
                dibujaMarcadores(app.VentanaInforme,index);
                end

            end 
     
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NombredearchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.NombredearchivoEditField = uieditfield(app.UIFigure, 'text');
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = '16036.png';
            app.Button_4.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'IconoPorovisional.jpeg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'IconoPorovisional.jpeg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'IconoPorovisional.jpeg';
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Icono provisional.png';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Simulación', 'Electromiograma', 'Marcadores'};
            app.DropDown.Value = 'Simulación';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AbrirInforme

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end