classdef Simulacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ANGULOSPanel                   matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        GRAFICARButton                 matlab.ui.control.Button
        GRAFICARButton_2               matlab.ui.control.Button
        SIMULACIONPanel                matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        Switch                         matlab.ui.control.Switch
        Slider                         matlab.ui.control.Slider
        PLAYButton                     matlab.ui.control.Button
        PAUSEButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        MODOVIRTUALLabel               matlab.ui.control.Label
        MODOREALLabel                  matlab.ui.control.Label
        PLAYButton_2                   matlab.ui.control.Button
        PAUSEButton_2                  matlab.ui.control.Button
        STOPButton_3                   matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        USUARIOPanel                   matlab.ui.container.Panel
        Image_2                        matlab.ui.control.Image
        NOMBREEditFieldLabel           matlab.ui.control.Label
        NOMBREEditField                matlab.ui.control.EditField
        EDADEditFieldLabel             matlab.ui.control.Label
        EDADEditField                  matlab.ui.control.EditField
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        Image                          matlab.ui.control.Image
        ELECTROMIOGRAMAPanel           matlab.ui.container.Panel
        UIAxes4                        matlab.ui.control.UIAxes
        CONECTARButton                 matlab.ui.control.Button
        TiemposegEditFieldLabel        matlab.ui.control.Label
        TiemposegEditField             matlab.ui.control.NumericEditField
        Panel                          matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SimulacinButton                matlab.ui.control.RadioButton
        EMGButton                      matlab.ui.control.RadioButton
        SensoresInercialesButton       matlab.ui.control.RadioButton
        MarcadoresvisualesButton       matlab.ui.control.RadioButton
        CALIBRACINDELSENSOREMGPanel    matlab.ui.container.Panel
        UIAxes4_2                      matlab.ui.control.UIAxes
        MXIMAButton                    matlab.ui.control.Button
        MNIMAButton                    matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        EditField                      matlab.ui.control.NumericEditField
        Lamp_2                         matlab.ui.control.Lamp
        EditField_2                    matlab.ui.control.NumericEditField
        PROCESADODEVDEOPanel           matlab.ui.container.Panel
        VelocidaddereproduccinEditField  matlab.ui.control.NumericEditField
        INICIARDETECCINDEMARCADORESButton  matlab.ui.control.Button
        FotogramasSliderLabel          matlab.ui.control.Label
        FotogramasSlider               matlab.ui.control.Slider
        PLAYButton_3                   matlab.ui.control.Button
        PAUSEButton_3                  matlab.ui.control.Button
        STOPButton_4                   matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        SehabilitarnlasfuncionesdedeteccindemarcadoresunaLabel  matlab.ui.control.Label
        VelocidaddereproduccinfotogramassegMximo20fpsLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        SIMULACIONPanel_2              matlab.ui.container.Panel
        UIAxes_3                       matlab.ui.control.UIAxes
        PLAYButton_4                   matlab.ui.control.Button
        PAUSEButton_4                  matlab.ui.control.Button
        STOPButton_5                   matlab.ui.control.Button
        Slider_2                       matlab.ui.control.Slider
        FotogramasSliderLabel_2        matlab.ui.control.Label
        ANGULOSPanel_2                 matlab.ui.container.Panel
        UIAxes2_2                      matlab.ui.control.UIAxes
        UIAxes3_2                      matlab.ui.control.UIAxes
        GRAFICARButton_4               matlab.ui.control.Button
        NombredearchivoEditFieldLabel  matlab.ui.control.Label
        NombredearchivoEditField       matlab.ui.control.EditField
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        PlanoSagitalButton             matlab.ui.control.RadioButton
        PlanoFrontalButton             matlab.ui.control.RadioButton
        seleccionarVideo               matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        %Variables que usaremos en el pchip para la interpolación de los
        %ángulos
        x=0:63.75:510;
        xq=0:1:510;
        
        
        %Prismas de mano, antebrazo y brazo
        wrist= [15    10  -110;15    20  -110;25    20  -110;25    10  -110;10    10  -130;10    20  -130;30    20  -130;30    10  -130];
        elbow=[10    10   -60;10    20   -60; 30    20   -60;30    10   -60; 15    10  -110;15    20  -110;25    20  -110;25    10  -110];
        shoulder=[ 5   5  -10;5   25   -10;  35    25   -10;35     5   -10;10    10   -60;10    20   -60;30    20   -60;30    10   -60];
       
        %BRAZO DERECHO
        %Ángulos de codo
        ACy=[ 90 90 90 90 0 90 90 90 90];%Pronosupinacion del codo
        ACr=[60    10     0     0     0     0     0    10    60];%Flexoextensión del codo
        
        %Ángulos de hombro
        AHy=[0     0     0     -5     -10     -5     0     0     0];%Rotación del hombro
        AHp=[ 0   -10   -10     5     5     5   -10   -10     0];%Abducción-Aducción del hombro
        AHr=[30    80    90    90    90    90    90    80    30];%Flexoextensión del hombro
        
        %BRAZO IZQUIERDO
        acy1 =[ 90 90 90 90 180 90 90 90 90];
        acr1=[60    10     0     0     0     0     0    10    60];
        ahy1=[0     0     0     -5    -10    -5     0     0     0];
        ahp1=[0   10   12    -5     -5    -5   12  10     0];
        ahr1=[30    80    90    90    90    90    90    80    30];
        
        simulat=false;
        
        %Ángulos de codo y hombro resultantes de la interpolación
        yqCY=[];
        %yqCP=[];
        yqCR=[];
        yqHY=[];
        yqHP=[];
        yqHR=[];
        
        %Nuestro contador en las iteraciones de movto extremidad superior
        j=1;
        
        %Ángulos máximos y mínimos de codo y hombro usados para el informe
        MYH=[];
        MPH=[];
        MRH=[];
        MIYH=[];
        MIPH=[];
        MIRH=[];
        MYC=[];
        MRC=[];
        MIYC=[];
        MIRC=[];
        
        %Vector de ángulos que recoge los máximos y mínimos hallados
        YH=[];
        PH=[];
        RH=[];
        YC=[];
        RC=[];
        
        %Ventanas a llamar
        VentanaInform;
        VentanaInformEMG;
        VentanaIMUS;
        VentanaMarcadores;
        
        %Slider
        value;
        %Switch
        valSwi ;
        
        %electromiografía
        vectorEMG=[];
        boolcalibracion= false;
        senal_final;
        
        %IMUS
        %Valores de cada scanf de los sensores
        imuCY=[];
        imuCR=[];
        imuHY=[];
        imuHP=[];
        imuHR=[];
        %
        simulatIMU=false;
        %
        YPRfh=[];
        YPRfc=[];
        
        %Marcadores
        simulatMarcadores=false;
        v;
        
        % Plano sagital
        centHombroVerde=[];
        centCodo=[];
        centMunecaRojo=[];
        centCadera=[];
        
        CCodo=[];
        CMunecaR=[];
        CHombro=[];
        CCadera=[];
        
        angHombroPitchFinal=[];
        angCodoPitchFinal=[];
        
        % Plano frontal
        centHombroVerde2=[];
        centMunecaRojo2=[];
        centMunecaAzul=[];
        centPechoAmarillo =[];
        
        CHombro2=[];
        CPecho=[];
        CMunecaR2=[];
        CMunecaA=[];
        
        AHombroYawFinal=[];
        ACodoRollFinal=[];
        envMeanC=[];
        envMeanH=[];
        envMeanH2=[];
        angCodoRollFinal=[];
        
        simulat4=false;
        valor;
        AHombroYawFinal0=[];
        
        
    end
    
    
    methods (Access = private)
        
        function modelRotated = applyRotation(~,model,punto_giro,yaw,pitch,roll)
            
            %************************ MATRICES DE ROTACIÓN:***********************
            %Una vez hallada la matriz de rotación rotamos el cuerpo(model) con
            %respecto del punto de giro.
            matrixYaw = [cosd(yaw),-sind(yaw),0;sind(yaw),cosd(yaw),0;0,0,1];%eje Z
            matrixPitch = [cosd(pitch),0,sind(pitch);0,1,0;-sind(pitch),0,cosd(pitch)];%eje Y
            matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);0,sind(roll),cosd(roll)];%eje X
            
            R = matrixYaw*matrixPitch*matrixRoll;%Necesario ya que algunos movimientos pueden estar formados de todos los grados de libertad.
            
            modelRotated = (model-repmat(punto_giro,size(model,1),1))*R;%lo traigo a mi nuevo (0,0,0)
            modelRotated = modelRotated + repmat(punto_giro,size(model,1),1);%lo devuelvo al espacio donde se encontraba
        end
        
        function R = rotationMatrix(~,yaw,pitch,roll)
            %************************ MATRICES DE ROTACIÓN:***********************
            %Hallamos la matriz de roatación dada una orientación(YPR) con
            %respecto del sist. ref. global.
            matrixYaw = [cosd(yaw),-sind(yaw),0;...
                sind(yaw),cosd(yaw),0;0,0,1];
            matrixPitch = [cosd(pitch),0,sind(pitch);...
                0,1,0;-sind(pitch),0,cosd(pitch)];
            matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);...
                0,sind(roll),cosd(roll)];
            R = matrixYaw*matrixPitch*matrixRoll;
        end
        
        function ypr = eulerAngles(~,R)
            %************************ ÁNGULOS DE EULER:***********************
            %Hallamos los ángulos dada una matriz de orientcaión de un
            %cuerpo con respecto del sistema de referencia local.
            ypr(1) = atan2d(R(2,1),R(1,1));
            ypr(2) = -asind(R(3,1));
            ypr(3) = atan2d(R(3,2),R(3,3));
        end
        
        function [YPRshoulderF,YPRelbowF] = globalanglestoLOCAL(~,data_doubleH,data_doubleC)
            
            s=size(data_doubleH,1);
            yprShoulder=[];
            yprElbow=[];
            YPRshoulderF=[];
            YPRelbowF=[];
            %VECTORES DE  REFERENCIA LOCAL:
            %YPR ORIGEN DE LA REF. LOCAL
            YPRhombroS=data_doubleH;
            YPRelbowS=data_doubleC;
            %YPRwristS=munecaSensor;
            
            for i=1:s
                
                %1)REALIZAMOS LAS ROTACIONES CON MATRICES DE ROTACIÓN INVERSAS, y YPRglobales (input)
                %a la inversa:
                %Nota: Aquí obtenemos las matrices de rotación de los cuerpos(MR_..) así como los cuerpos
                %rotados locales (YPR...)
                MR_hombro = rotationMatrix(YPRhombroS(i,1),YPRhombroS(i,2),YPRhombroS(i,3));
                YPR_elbow(i,:) = MR_hombro'*YPRelbowS(i,:)';
                MR_codo = rotationMatrix(YPR_elbow(i,1),YPR_elbow(i,2),YPR_elbow(i,3));
                %YPR_wrist(i,:) = MR_codo'*MR_hombro'*YPRwristS(i,:)';
                %MR_wrist = rotationMatrix(YPR_wrist(i,1),YPR_wrist(i,2),YPR_wrist(i,3));
                
                %2)ÁNGULOS LOCALES
                %Nota: Aquí obteemos los ángulos locales de las matrices de rot representativas
                %de la orientacion de nuestro cuerpos en el sist. ref local obtenidas en el
                % aparatdo anterior.
                %A)YPR HOMBRO
                yprShoulder(i,:) = eulerAngles(MR_hombro);
                %B)YPR CODO
                yprElbow(i,:) = eulerAngles(MR_codo);
                %C)YPR MUNECA
                % yprWrist (i,:)= eulerAngles(MR_wrist);
                
                %VECTORES FINALES
                YPRshoulderF=[YPRshoulderF;yprShoulder(i,:)];
                YPRelbowF=[YPRelbowF;yprElbow(i,:)];
                %YPRwristF=[YPRwristF;yprWrist(i,:)];
            end
        end
        
        function readIMUgraph(app,eje)
            %************************* LEER DE LAS IMUS:***************************
            % 1)Leemos de los sensores inerciales unos ángulos con respecto del sistema global
            % 2)Hallamos las matrices de rotación(mediante rotationMatrix)
            % 3)Calculamos mediante las inversas de las matrix rot. resultantes los
            % ángulos con respecto del sistema local(mediante__________)
            % 4)Dibujamos dichos valores (mediante dibujaBrazo), con un 'for' ya que en tan
            % solo un scanf percibimos varios ypr.
            %***********************************************************************
            % Creamos el objeto "puerto serie" con la misma frecuencia de datos que tengamos en Arduino
            delete(instrfind({'Port'},{'/dev/cu.usbmodem14101'}))
            puerto_serial=serial('/dev/cu.usbmodem14101');
            puerto_serial.BaudRate=115200;
            puerto_serial.DataTerminalReady = 'off';
            warning('off','MATLAB:serial:fscanf:unsuccessfulRead');
            % Abrimos el puerto serie
            disp("Conecta el puerto serie")
            pause(1);
            fopen(puerto_serial);
            pause(2);
            
            %Imprimirá las 20 oraciones primeras del arduino para la conexión de las
            %IMUS.
            for i = 1:20
                out1 = fscanf(puerto_serial);
                disp(out1);
                i%=i+1;
            end
            
            %Tiempo en segundos
            tiempo = 0;
            %Envíamos la letra A que espera Arduino para empezar a obtener los valores
            %de las IMUS:
            fprintf(puerto_serial,'A');
            tic
            while(tiempo < 3)
                % Leo lo que hay en el puerto serie
                out = fscanf(puerto_serial);
                %disp(out);
                %Separamos los datos por ',' y por ';': Se separaran en la
                %misma fila por comas y en diferentes filas por ';'
                data_cell = split(out,[" ",";"]);
                data_String= string(data_cell);
                
                %Al estar en un bucle donde se corta la conexión por puerto serie por tiempo, puede
                %hacer que los valores que lleguen se corten, por ejemplo:
                %YPR=(X,Y,Z), puede llegar en el siguiente formato YPR=(X,Y  .Esto
                %quiere decir que no es útil dicha info, es mejor desecharla, por lo
                %que:
                %Si la primera fila no tiene tres coordenadas, se elimina:
                if size(data_String(1),1)<3
                    data_String(1)=[];
                end
                %Si la última fila no tiene tres coordenadas, se elimina:
                if size(data_String(end),1)<3
                    data_String(end)=[];
                end
                
                % En primer lugar, recibimos desde Arduino Chest, sin embargo, esta fila es eliminada y
                %por tanto, nuestro primer vector ypr(1º elemento de data_double) es de
                %shoulder.
                %Convertimos a string, para hacer un split para que los 3 elementos en
                %una fila se conviertan cada uno en una columna, esto es ahora tenemos
                %3 columnas y no 3 valores separados en una misma columna:
                data_String_Split= split(data_String);
                %Así una vez tenemos por fin una matrix, cada valor en su recuadro, es
                %facil convertir esta misma matriz(string matrix) a una double
                %matrix(justo lo que necesitamos):
                data_double=double(data_String_Split);
                %Al hacer el split se crea una primera columna vacía, no se ha
                %estudiado las razones en este presente, sin embargo decicimos
                %proceder a eliminarla en una 1º versión:
                data_double(:,1)=[];
                %size(data_double,1)
                
                %Separamos vectores de cada sensor(podríamos hacerlo posteriormente,
                %sin embargo, no podremos tener en cuenta las veces que se eliminaron
                %las filas(1º y última de cada iteración) y por ello no sabremos cuál
                %es cuál).Los pares son hombro y los impares pecho:
                data_double_Hombro=data_double(1:2:end,:)
                data_double_Codo=data_double;
                data_double_Codo(1:2:end,:)=[]
                
                %Si el tamaño de la matriz Hombro es mayor quiere decir que se rompió la
                %primera pareja YPR_Pecho-YPR_Hombro, por lo que eliminamos la 1º fila de
                %Hombro para que sean correspondientes:
                if size(data_double_Hombro,1)>size(data_double_Codo,1)
                    data_double_Hombro(1,:)=[];
                end
                %****************************************************************
                %********************* CÁLCULO ÁNGULOS GLOBALES *****************
                %****************************************************************
                [YPRh,YPRc] = globalanglestoLOCAL(app,data_double_Hombro,data_double_Codo)
                %Dichos ángulos se van acumulando en una varible final que será utilizada
                %para el dibujo de las gráficas y para el futuro informe
                app.YPRfh=[app.YPRfh;YPRh];
                app.YPRfc=[app.YPRfc;YPRc];
                %****************************************************************
                %************************** DIBUJAR BRAZO ***********************
                %****************************************************************
                %eje=app.UIAxes;
                S=size(YPRh);
                for i=1:S
                    cla(app.UIAxes);
                    dibujaBrazoIMU(app,i,eje,YPRh,YPRc)
                    if i==(S)
                        app.simulatIMU= false;
                    end
                    if app.simulatIMU== false
                        break
                    end
                    
                end
                
                if app.simulatIMU== false
                    break
                end
                %VAMOS ACTUALIZANDO LAS MATRICES FINALES:
                %vectorH = [vectorH;data_double_Hombro];
                %vectorC= [vectorC;data_double_Codo];
                tiempo = toc;
                fprintf('Estamos en ello no te desesperes!');
            end%while
            
            %El script de Arduino tiene programado que si recibe una B, deja de leer de
            %los sensores y por tanto, deja de enviar info por el puerto serie. Por lo
            %que es la forma de parar de recibir info:
            fprintf(puerto_serial,'B');
            pause(1)
            out = fscanf(puerto_serial);
            
            %Una vez parado el envío de datos, cerramos el puerto serie:
            fclose(puerto_serial);
            delete(puerto_serial);
            clear puerto_serial;
        end
        
        
        function dibujaBrazo(app,i,eje)
            
            %****************** APLICO ROTACIONES A CADA ARTICULACIÓN:***************
            
            %*************************
            %Ángulos(MUÑECA)
            
            %1)Rotacion de la muñeca
            v3= applyRotation(app,app.wrist,sum(app.wrist(1:4,:))/4,0,0,0);
            %sum(wrist(1:4,:))/4 = suma los 4 vertices de la primera 4 y lo divide entre 4 para hallar el
            %punto medio que sea el punto de giro para las rotaciones los argumentos de entrada son yaw=0, pitch=0, roll=0;
            %**************************
            
            
            %**************************
            %Ángulos(CODO)
            app.yqCY=pchip(app.x,app.ACy,app.xq);%interpolación de los ángulos de flexoextensión del codo
            app.yqCR=pchip(app.x,app.ACr,app.xq);%interpolación de los ángulos de pronosupinación del codo
            
            %2)Rotacion del codo
            %Hay que hacer v2 pero tambien aplicarselo a v3 para que no se desajuste
            v2= applyRotation(app,app.elbow,sum(app.elbow(1:4,:))/4,app.yqCY(i),0,app.yqCR(i));
            v3= applyRotation(app,v3,sum(app.elbow(1:4,:))/4,app.yqCY(i),0,app.yqCR(i));
            %***************************
            
            
            %***************************
            %Ángulos(HOMBRO)
            app.yqHY=pchip(app.x,app.AHy,app.xq);%Interpolación de los ángulos de abducción-aducción del hombro
            app.yqHP=pchip(app.x,app.AHp,app.xq);%Interpolación de los ángulos de flexoestención del hombro
            app.yqHR=pchip(app.x,app.AHr,app.xq);%Interpolación de los ángulos de rotación del hombro
            
            %3)Rotacion del hombro
            v1= applyRotation(app,app.shoulder,sum(app.shoulder(2:3,:))/2,app.yqHY(i),app.yqHP(i),app.yqHR(i));%aplico giro al hombr0
            v2= applyRotation(app,v2,sum(app.shoulder(2:3,:))/2,app.yqHY(i),app.yqHP(i),app.yqHR(i));
            v3= applyRotation(app,v3,sum(app.shoulder(2:3,:))/2,app.yqHY(i),app.yqHP(i),app.yqHR(i));
            %***************************
            
            
            %****************************** DIBUJAR BRAZO: ***********************
            
            %DIBUJAR PRISMAS
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5;2 3 7 6;3 4 8 7; 4 1 5 8];%Orden de los vértices para formar cada una de las caras del prisma
            
            %MODIFICACIÓN EJES:
            rotate3d(eje,'on');%Posibilidad de girar los ejes con el ratón
            
            %GRAFICAR LOS PRISMAS
            patch(eje,'vertices',v1,'faces',f,'FaceVertexCData',10, 'FaceColor', 'flat')
            hold(eje,'on');
            patch(eje,'vertices',v2,'faces',f, 'FaceVertexCData', 70, 'FaceColor', 'flat')
            patch(eje,'vertices',v3,'faces',f, 'FaceVertexCData', 140, 'FaceColor', 'flat')
            
            
            %DIBUJAR ESFERAS
            [X,Y,Z] = sphere;%Esferas
            
            %Multiplicamos por 10 para que se vean mejor
            X=X*10;
            Y=Y*10;
            Z=Z*10;
            
            %Graficamos las esferas adyacentes a cada uno de los prismas
            surf(eje,X+sum(v1(1:4,1)/4),Y+sum(v1(1:4,2)/4),Z+sum(v1(1:4,3)/4));
            surf(eje,X+sum(v2(1:4,1)/4),Y+sum(v2(1:4,2)/4),Z+sum(v2(1:4,3)/4));
            surf(eje,X+sum(v3(1:4,1)/4),Y+sum(v3(1:4,2)/4),Z+sum(v3(1:4,3)/4));
            hold(eje,'off');
            drawnow limitrate;
            pause(0.0001);
            
        end
        
        function dibujaBrazoIMU(app,i,eje,data_doubleH,data_doubleC)
            
            %****************** APLICO ROTACIONES A CADA ARTICULACIÓN:***************
            
            %*************************
            %Ángulos(MUÑECA)
            
            %1)Rotacion de la muñeca
            v3= applyRotation(app,app.wrist,sum(app.wrist(1:4,:))/4,0,0,0);
            %sum(wrist(1:4,:))/4 = suma los 4 vertices de la primera 4 y lo divide entre 4 para hallar el
            %punto medio que sea el punto de giro para las rotaciones los argumentos de entrada son yaw=0, pitch=0, roll=0;
            %**************************
            
            
            %**************************
            %Ángulos(CODO)
            app.imuCY=data_doubleC(:,1);
            app.imuCR=data_doubleC(:,3);
            
            %2)Rotacion del codo
            %Hay que hacer v2 pero tambien aplicarselo a v3 para que no se desajuste
            v2= applyRotation(app,app.elbow,sum(app.elbow(1:4,:))/4,app.imuCY(i),0,app.imuCR(i));
            v3= applyRotation(app,v3,sum(app.elbow(1:4,:))/4,app.imuCY(i),0,app.imuCR(i));
            %***************************
            
            
            %***************************
            %Ángulos(HOMBRO)
            app.imuHY=data_doubleH(:,1);
            app.imuHP=data_doubleH(:,2);
            app.imuHR=data_doubleH(:,3);
            
            %3)Rotacion del hombro
            v1= applyRotation(app,app.shoulder,sum(app.shoulder(2:3,:))/2,app.imuHY(i),app.imuHP(i),app.imuHR(i));%aplico giro al hombr0
            v2= applyRotation(app,v2,sum(app.shoulder(2:3,:))/2,app.imuHY(i),app.imuHP(i),app.imuHR(i));
            v3= applyRotation(app,v3,sum(app.shoulder(2:3,:))/2,app.imuHY(i),app.imuHP(i),app.imuHR(i));
            %***************************
            
            
            %****************************** DIBUJAR BRAZO: ***********************
            
            %DIBUJAR PRISMAS
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5;2 3 7 6;3 4 8 7; 4 1 5 8];%Orden de los vértices para formar cada una de las caras del prisma
            
            %MODIFICACIÓN EJES:
            rotate3d(eje,'on');%Posibilidad de girar los ejes con el ratón
            
            %GRAFICAR LOS PRISMAS
            patch(eje,'vertices',v1,'faces',f,'FaceVertexCData',10, 'FaceColor', 'flat')
            hold(eje,'on');
            patch(eje,'vertices',v2,'faces',f, 'FaceVertexCData', 70, 'FaceColor', 'flat')
            patch(eje,'vertices',v3,'faces',f, 'FaceVertexCData', 140, 'FaceColor', 'flat')
            
            
            %DIBUJAR ESFERAS
            [X,Y,Z] = sphere;%Esferas
            
            %Multiplicamos por 10 para que se vean mejor
            X=X*10;
            Y=Y*10;
            Z=Z*10;
            
            %Graficamos las esferas adyacentes a cada uno de los prismas
            surf(eje,X+sum(v1(1:4,1)/4),Y+sum(v1(1:4,2)/4),Z+sum(v1(1:4,3)/4));
            surf(eje,X+sum(v2(1:4,1)/4),Y+sum(v2(1:4,2)/4),Z+sum(v2(1:4,3)/4));
            surf(eje,X+sum(v3(1:4,1)/4),Y+sum(v3(1:4,2)/4),Z+sum(v3(1:4,3)/4));
            hold(eje,'off');
            drawnow limitrate;
            pause(0.0001);
            
        end
        
        function dibujaBrazo_video(app,i,eje)
            
            %****************** APLICO ROTACIONES A CADA ARTICULACIÓN:***************
            ang1 = app.envMeanH(i); % Flexoextension Hombro
            ang2 = app.envMeanH2(i); % Abduccion aduccion Hombro
            ang3 = app.angCodoRollFinal(i);  % Pronosupinacion Codo
            ang4 = app.envMeanC(i); % Flexoextension Codo
          
            %*************************
            %Ángulos(MUÑECA)
            
            %1)Rotacion de la muñeca
            v3= applyRotation(app,app.wrist,sum(app.wrist(1:4,:))/4,0,0,0);
            %sum(wrist(1:4,:))/4 = suma los 4 vertices de la primera 4 y lo divide entre 4 para hallar el
            %punto medio que sea el punto de giro para las rotaciones los argumentos de entrada son yaw=0, pitch=0, roll=0;
            %**************************
            
            %**************************
            %Ángulos(CODO)
            %2)Rotacion del codo
            %Hay que hacer v2 pero tambien aplicarselo a v3 para que no se desajuste
            v2= applyRotation(app,app.elbow,sum(app.elbow(1:4,:))/4,ang3,0,ang4);
            v3= applyRotation(app,v3,sum(app.elbow(1:4,:))/4,ang3,0,ang4);
            %***************************
            
            %***************************
            %Ángulos(HOMBRO)
            %3)Rotacion del hombro
            v1= applyRotation(app,app.shoulder,sum(app.shoulder(2:3,:))/2,0,ang2,ang1);%aplico giro al hombr0
            v2= applyRotation(app,v2,sum(app.shoulder(2:3,:))/2,0,ang2,ang1);
            v3= applyRotation(app,v3,sum(app.shoulder(2:3,:))/2,0,ang2,ang1);
            %***************************
            
            %****************************** DIBUJAR BRAZO: ***********************
            
            %DIBUJAR PRISMAS
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5;2 3 7 6;3 4 8 7; 4 1 5 8];%Orden de los vértices para formar cada una de las caras del prisma
            
            %MODIFICACIÓN EJES:
            rotate3d(eje,'on');%Posibilidad de girar los ejes con el ratón
            view(eje);
            axis(eje,'equal','on');
            
            %GRAFICAR LOS PRISMAS
            patch(eje,'vertices',v1,'faces',f,'FaceVertexCData',40, 'FaceColor', 'flat')
            hold(eje,'on');
            axis(eje,'equal','on');
            patch(eje,'vertices',v2,'faces',f, 'FaceVertexCData', 60, 'FaceColor', 'flat')
            patch(eje,'vertices',v3,'faces',f, 'FaceVertexCData', 140, 'FaceColor', 'flat')
            
            %DIBUJAR ESFERAS
            [X,Y,Z] = sphere;%Esferas
            
            %Multiplicamos por 10 para que se vean mejor
            X=X*10;
            Y=Y*10;
            Z=Z*10;
            
            %Graficamos las esferas adyacentes a cada uno de los prismas
            surf(eje,X+sum(v1(1:4,1)/4),Y+sum(v1(1:4,2)/4),Z+sum(v1(1:4,3)/4));
            surf(eje,X+sum(v2(1:4,1)/4),Y+sum(v2(1:4,2)/4),Z+sum(v2(1:4,3)/4));
            surf(eje,X+sum(v3(1:4,1)/4),Y+sum(v3(1:4,2)/4),Z+sum(v3(1:4,3)/4));
            hold(eje,'off');
            
        end
        
        function graph_true(app)
            %Función que recorre todos los valores de los ángulos y se lo pasa a la función
            %graficarBrazo para graficar la simulación.
            while(app.simulat)
                eje=app.UIAxes;
                for i=1:101
                    cla(app.UIAxes);
                    app.Slider.Value=i;
                    dibujaBrazo(app,i,eje);
                    
                    if i==100
                        app.simulat= false;
                    end
                    
                    if app.simulat== false
                        break
                    end
                end
            end
        end
        
        function graph_true_IMUS(app,S)
            %Función que recorre todos los valores de los ángulos y se lo pasa a la función
            %graficarBrazo para graficar la simulación. Siendo S el tam del
            %vector de ángulos a dibujar.
            while(app.simulat)
                eje=app.UIAxes;
                for i=1:S
                    
                    cla(app.UIAxes);
                    
                    dibujaBrazoIMU(app,i,eje);
                    
                    if i==(S-1)
                        app.simulatIMU= false;
                    end
                    
                    if app.simulatIMU== false
                        
                        break
                    end
                end
            end
        end
        
        function graph_true_video(app)
            %Función que recorre todos los valores de los ángulos y se lo pasa a la función
            %graficarBrazo para graficar la simulación.
            while(app.simulat4)
                eje=app.UIAxes_3;
                for i=1:510
                    cla(app.UIAxes_3);
                    app.Slider_2.Value=i;
                    dibujaBrazo_video(app,i,eje);
                    drawnow limitrate;
                    pause(0.0001);
                    
                    if i==510
                        app.simulat4= false;
                    end
                    
                    if app.simulat4== false
                        break
                    end
                end
            end
        end
        
        
        function vector=emg(app,ejes,timeIN)
            app.vectorEMG=[];
            % quiero capturar X muestras
            % Creamos el objeto "puerto serie" con la misma frecuencia de datos que tengamos en Arduino
            delete(instrfind({'Port'},{'/dev/cu.usbmodem14101'}))
            puerto_serial=serial('/dev/cu.usbmodem14101');
            puerto_serial.BaudRate=9600;
            puerto_serial.DataTerminalReady = 'off';
            warning('off','MATLAB:serial:fscanf:unsuccessfulRead');
            % Abrimos el puerto serie
            pause(1);
            fopen(puerto_serial);
            fprintf(puerto_serial,'A');
            out = fscanf(puerto_serial);
            pause(0.5);
            
            %Tiempo en segundos
            tiempo = 0;
            %Entramos en un bucle en el que nos quedamos hasta que el tamaño del vector
            %sea el que hemos decidido
            tic
            while(tiempo < timeIN)
                % Leo lo que hay en el puerto serie y lo limpio
                out = fscanf(puerto_serial);
                % El problema es que puedo tener varios valores, por lo que tengo que
                % partir la lectura en tantos valores como obtenga. Para eso lo más
                % fácil es escribrlos en el puerto serie usando un separador.
                data = split(out,',');
                % Concatenamos los valores con los que ya tenemos guardados
                for i = 1:size(data,1)
                    app.vectorEMG = [app.vectorEMG;str2double(data{i,1})];
                end
                
                %___________________________________
                %PROCESADO DE LA SEÑAL
                
                % Normalizacion de la señal
                %Si estamos en la calibración no normalizaremos
                if(app.boolcalibracion==false)
                    Sn = app.vectorEMG/max(app.vectorEMG);
                    % Rectificacion de la señal
                    Sn(i) = abs(Sn(i));
                    % Envolvente - Integral de la señal
                    app.senal_final = envelope(Sn);
                elseif (app.boolcalibracion==true)
                    app.senal_final=app.vectorEMG;
                end
                
                %___________________________________
                %GRAFICA LA SEÑAL
                plot(ejes,app.senal_final, 'r');
                drawnow;
                
                tiempo = toc;
                
            end%while
            %___________________________________
            %DEVUELVE ARGUMENTO DE SALIDA
            vector=app.vectorEMG;
            
            %___________________________________
            %ENVIAR CHAR Y CIERRE DE PUERTO
            %salida = vector(1,end);
            %save salida salida
            fprintf(puerto_serial,'B');
            pause(1)
            out = fscanf(puerto_serial);
            
            while(~isnan(str2double(out)))
                app.vectorEMG = [app.vectorEMG;str2double(out)];
                out = fscanf(puerto_serial);
            end
            
            fclose(puerto_serial);
            delete(puerto_serial);
            clear puerto_serial;
            
            
        end%emg
        
        % Esta funcion detecta los marcadores y representa su posición
        % sobre un vídeo grabado desde un plano sagital
        function planoSagital(app,i)
            
            cla(app.UIAxes_2); % Limpiamos la zona de reproducción
            app.FotogramasSlider.Value=i; % El slider irá tomando el valor del fotograma que muestra la reproducción
            
            video=read(app.v,i); % Nos da los fotogramas del video uno a uno
            
            imR=(video(:,:,1)); % Matriz del primer componenete RGB (rojo)
            imG=(video(:,:,2)); % Matriz del segundo componente RGB (verde)
            imB=(video(:,:,3)); % Matriz del tercer componente RGB (azul)
            
            % ============================= DETECCIONES DE MARCADORES =============================
            
            % Para captar el marcador lo haremos por umbralización --> si los pixeles
            % cumplen las condiciones que ponemos se podrán en 1 y sino se pondra en 0
            
            %Imagen binaria de cada marcador
            marcador1 =video(:,:,1).*0; %1º marcador hace referencia al marcador de color verde codo
            marcador1(imR>73 & imR<134 & imG>154 & imG<255 & imB>58 & imB<119)=1;
            
            marcador2= video(:,:,1).*0 ; %2º marcador hace referencia al marcador de color rojo muñeca
            marcador2(imR>120 & imR<255 & imG>3 & imG<58 & imB>6 & imB<60)=1;
            
            marcador3= video(:,:,1).*0;%3º marcador hace referencia al marcador de color azul hombro
            marcador3(imR>0 & imR<40 & imG>35 & imG<73 & imB>76 & imB<145)=1;
            
            marcador4 =video(:,:,1).*0;%4º marcador hace referencia al marcador de color amarillo cadera
            marcador4(imR>140 & imR<255 & imG>100 & imG<155 & imB>0 & imB<50)=1;
            
            % ============================= CÁLCULO DE NUEVAS VARIABLES =============================
            
            % Elimina los objetos cuyo número de píxeles sea <5
            imgFilled1 = bwareaopen (marcador1,5);% siendo 5 el numero de pixeles que quiero eliminar por cada marcador
            imgFilled2 = bwareaopen (marcador2,5);
            imgFilled3 = bwareaopen (marcador3,5);
            imgFilled4 = bwareaopen (marcador4,5);
            
            % Detección de objetos por cada marcador:
            [L1,Ne1] = bwlabel(imgFilled1);
            [L2,Ne2] = bwlabel(imgFilled2);
            [L3,Ne3] = bwlabel(imgFilled3);
            [L4,Ne4] = bwlabel(imgFilled4);
            
            % Calculo de propiedades de los objetos para calcular luego el centroide de cada marcador
            propied1=regionprops(L1);
            propied2=regionprops(L2);
            propied3=regionprops(L3);
            propied4=regionprops(L4);
            
            %Calculo de centroides (Hombro, Codo, Muneca y Pecho)
            if (isempty(propied1)&& size(app.CCodo,1)>0)
                app.centCodo=app.CCodo(size(app.CCodo,1),:);
            else
                app.centCodo=propied1.Centroid;
            end
            app.CCodo=[app.CCodo;app.centCodo];
            
            if (isempty(propied2) && size(app.CMunecaR,1)>0)
                app.centMunecaRojo= app.CMunecaR(size(app.CMunecaR,1),:);
            else
                app.centMunecaRojo=propied2.Centroid;
            end
            app.CMunecaR=[app.CMunecaR;app.centMunecaRojo];
            
            if (isempty(propied3) && size(app.CHombro,1)>0)
                centHombroAzul= app.CHombro(size(app.CHombro,1),:);
            else
                centHombroAzul=propied3.Centroid;
            end
            app.CHombro=[app.CHombro;centHombroAzul];
            
            if (isempty(propied4) && size(app.CCadera,1)>0)
                app.centCadera=app.CCadera(size(app.CCadera,1),:);
            else
                app.centCadera=propied4.Centroid;
            end
            app.CCadera=[app.CCadera;app.centCadera];
            
            % ====================== REPRODUCCIÓN VÍDEO CON POSICION DE MARCADORES ======================
            imshow(video,'Parent', app.UIAxes_2);
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,app.CCodo(i,1),app.CCodo(i,2), 'm+');
            plot(app.UIAxes_2,app.CHombro(i,1),app.CHombro(i,2), 'm+');%imprimo un centroide con su cordenada x e y
            plot(app.UIAxes_2,app.CMunecaR(i,1),app.CMunecaR(i,2),'w+');
            plot(app.UIAxes_2,app.CCadera(i,1),app.CCadera(i,2), 'w+');
            pause(1/app.VelocidaddereproduccinEditField.Value); % Velocidad de reproduccion elegida por el usuario
            hold(app.UIAxes_2,'off');
            drawnow limitrate; % Permite ir actualizando la reproduccion sobre la grafica
            
        end
        
        
        % Esta funcion detecta los marcadores y representa su posición
        % sobre un vídeo grabado desde un plano frontal
        function planoFrontal(app,i)
            
            cla(app.UIAxes_2); % Limpiamos la zona de reproducción
            app.FotogramasSlider.Value=i; % El slider irá tomando el valor del fotograma que muestra la reproducción
            
            video=read(app.v,i); % Nos da los fotogramas del video uno a uno
            
            imR=(video(:,:,1)); % Matriz del primer componenete RGB (rojo)
            imG=(video(:,:,2)); % Matriz del segundo componente RGB (verde)
            imB=(video(:,:,3)); % Matriz del tercer componente RGB (azul)
            
            % ============================= DETECCIONES DE MARCADORES =============================
            
            % Para captar el marcador lo haremos por umbralización --> si los pixeles
            % cumplen las condiciones que ponemos se podrán en 1 y sino se pondra en 0
            
            %Imagen binaria de cada marcador
            marcador1 =video(:,:,1).*0; %1º marcador hace referencia al marcador de color verde hombro
            marcador1(imR>110 & imR<134 & imG>210 & imG<255 & imB>90 & imB<130)=1;
            
            marcador2= video(:,:,1).*0 ; %2º marcador hace referencia al marcador de color rojo muñeca
            marcador2(imR>141 & imR<191 & imG>9 & imG<57 & imB>12 & imB<60)=1;
            
            marcador3= video(:,:,1).*0;%3º marcador hace referencia al marcador de color azul muñeca
            marcador3(imR>0 & imR<19 & imG>46 & imG<75 & imB>121 & imB<193)=1;
            
            marcador4 =video(:,:,1).*0;%4º marcador hace referencia al marcador de color amarillo pecho
            marcador4(imR>195 & imR<255 & imG>141 & imG<213 & imB>15 & imB<101)=1;
            
            % ============================= CÁLCULO DE NUEVAS VARIABLES =============================
            
            % Elimina los objetos cuyo número de píxeles sea <5
            imgFilled1 = bwareaopen (marcador1,5);% siendo 5 el numero de pixeles que quiero eliminar por cada marcador
            imgFilled2 = bwareaopen (marcador2,5);
            imgFilled3 = bwareaopen (marcador3,5);
            imgFilled4 = bwareaopen (marcador4,5);
            
            % Detección de objetos por cada marcador:
            [L1,Ne1] = bwlabel(imgFilled1);
            [L2,Ne2] = bwlabel(imgFilled2);
            [L3,Ne3] = bwlabel(imgFilled3);
            [L4,Ne4] = bwlabel(imgFilled4);
            
            % Calculo de propiedades de los objetos para calcular luego el centroide de cada marcador
            propied1=regionprops(L1);
            propied2=regionprops(L2);
            propied3=regionprops(L3);
            propied4=regionprops(L4);
            
            %Calculo de centroides (Hombro, Muneca y Pecho)
            
            % Si no detecta un marcador, coge el valor del centroide anterior
            if (isempty(propied1) && size(app.CHombro2,1)>0)
                app.centHombroVerde2=app.CHombro2(size(app.CHombro2,1),:);
            else
                app.centHombroVerde2=propied1.Centroid;
            end
            app.CHombro2 = [app.CHombro2;app.centHombroVerde2];
            
            % Si no detecta el marcador rojo, detecta el azul
            % Calculamos ángulo de pronosupinación del codo según lo
            % anterior (marcador rojo = 90º ; marcador azul = 0º)
            if (isempty(propied3))
                app.centMunecaRojo2=propied2.Centroid;
                angRC = 90;
            else
                app.centMunecaAzul=propied3.Centroid;
                angRC = 0;
            end
            
            app.CMunecaR2=[app.CMunecaR2;app.centMunecaRojo2];
            app.CMunecaA=[app.CMunecaA;app.centMunecaAzul];
            app.ACodoRollFinal=[app.ACodoRollFinal,angRC];
            
            if (isempty(propied4) && size(app.CPecho,1)>0)
                app.centPechoAmarillo=app.CPecho(size(app.CPecho,1),:);
            else
                app.centPechoAmarillo=propied4.Centroid;
            end
            app.CPecho=[app.CPecho;app.centPechoAmarillo];
            
            % ====================== REPRODUCCIÓN VÍDEO CON POSICION DE MARCADORES ======================
            imshow(video,'Parent', app.UIAxes_2);
            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,app.CHombro2(i,1),app.CHombro2(i,2), 'm+');%imprimo un centroide con su cordenada x e y
            plot(app.UIAxes_2,app.CPecho(i,1),app.CPecho(i,2), 'w+');
            if (isempty(propied3))
                plot(app.UIAxes_2,app.centMunecaRojo2(1,1),app.centMunecaRojo2(1,2), 'w+');
            else
                plot(app.UIAxes_2,app.centMunecaAzul(1,1),app.centMunecaAzul(1,2), 'w+');
            end
            pause(1/app.VelocidaddereproduccinEditField.Value); % Velocidad de reproduccion elegida por el usuario
            hold(app.UIAxes_2,'off');
            drawnow limitrate; % Permite ir actualizando la reproduccion sobre la grafica
            
        end
        
        % Esta funcion calcula los angulos de flexoextension del hombro y
        % codo a partir de un video de plano sagital
        function calculoAngSagital(app)
            
            for i=1:510
                %%%%%%%%%%%%%%CALCULO DE ÁNGULOS%%%%%%%%%%%%%%%%%%%%%%%%%%
                %ÁNGULO DE FLEXOEXTENSIÓN DEL HOMBRO
                %El objetivo es construir un triángulo escaleno con los centros del
                %hombro,codo y cadera para sacar el ángulo de flexoextensión del hombro, para ello sacamos la
                %distancia que hay entre el hombro y el codo(brazo), entre el hombro y la
                %cadera (tronco) y entre el codo y cadera.
                
                %el centroide nos da dos coordenadas por lo que la distancia del brazo se
                %descompondrá en x e y para luego caclular la distancia de la misma
                distanciaBrazo= app.CCodo(i,:)-app.CHombro(i,:);
                disBrazoX=(distanciaBrazo(1));
                disBrazoY=(distanciaBrazo(2));
                
                distanciaTronco= app.CCadera(i,:)-app.CHombro(i,:);
                disTroncoX=(distanciaTronco(1));
                disTroncoY=(distanciaTronco(2));
                
                distanciaCodo_Cadera= app.CCadera(i,:)-app.CCodo(i,:);
                disCodo_CaderaX=(distanciaCodo_Cadera(1));
                disCodo_CaderaY=(distanciaCodo_Cadera(2));
                
                %Cálculamos las distancias del triángulo escaleno
                brazo=sqrt((disBrazoX^2)+ (disBrazoY^2));
                codo_cadera=sqrt((disCodo_CaderaX^2)+ (disCodo_CaderaY^2));
                tronco=sqrt((disTroncoX^2)+(disTroncoY^2));
                
                %Una vez que hemos construido el triángulo conociendo sus distancia ,
                %calcularemos el ángulo de flexoextensión que forma el hombro con el
                %teorema del coseno ----> a^2=b^2+c^2-2*b*a*cos(ang), despejando coseno del
                %angulo nos queda lo siguiente a^2-b^^-c^
                tCoseno=((codo_cadera^2-tronco^2-brazo^2)/((-2)*(tronco*brazo)));%siendo a=codo_cadera,b=tronco,c=brazo)
                
                %Hemos obtenido el cos(ang) para extraer el ang debemos hacer la
                %arccoseno
                arcHombro= acos(tCoseno);
                %El ángulo nos lo dan en radianes y hay que pasarlo a grados para su uso
                angHP= rad2deg(arcHombro);
                app.angHombroPitchFinal=[app.angHombroPitchFinal,angHP];
                
                %%%%%%%%%%%%%ANGULO DE FLEXOEXTENSION DEL CODO%%%%%%%%%%%%%%%%%%%
                distanciaAntebrazo= app.CMunecaR(i,:)-app.CCodo(i,:);
                disAntebrazoX=(distanciaAntebrazo(1));
                disAntebrazoY=(distanciaAntebrazo(2));
                
                distanciaHombro_Muneca= app.CMunecaR(i,:)-app.CHombro(i,:);
                disHombro_MunecaX=(distanciaHombro_Muneca(1));
                disHombro_MunecaY=(distanciaHombro_Muneca(2));
                
                %Cálculamos las distancias del triángulo escaleno
                antebrazo=sqrt((disAntebrazoX^2)+ (disAntebrazoY^2));
                hombro_muneca=sqrt((disHombro_MunecaX^2)+ (disHombro_MunecaY^2));
                
                %Una vez que hemos construido el triángulo conociendo sus distancia ,
                %calcularemos el ángulo de flexoextensión que forma el hombro con el
                %teorema del coseno ----> a^2=b^2+c^2-2*b*a*cos(ang), despejando coseno del
                %angulo nos queda lo siguiente a^2-b^^-c^
                tCoseno1=((hombro_muneca^2-antebrazo^2-brazo^2)/((-2)*(antebrazo*brazo)));%siendo a=codo_cadera,b=tronco,c=brazo)
                
                %Hemos obtenido el cos(ang) para extraer el ang debemos hacer la
                %arccoseno
                arcCodo= acos(tCoseno1);
                %El ángulo nos lo dan en radianes y hay que pasarlo a grados para su uso
                angCPITCH= 180 - rad2deg(arcCodo); % Angulo suplementario
                app.angCodoPitchFinal=[app.angCodoPitchFinal,angCPITCH];
            end
        end
        
        % Esta funcion calcula los angulos de abduccion-aduccion del hombro y a partir
        % de un video de plano frontal
        function calculoAngFrontal(app)
            for i = 1:510
                %CÁLCULO DE ÁNGULOS
                %%%%%%%%%%%%%%%ÁNGULO DE ABDUCCIÓN Y ADUCCIÓN DEL HOMBRO%%%%%%%%%%%%%%%%%
                % TODO - cambiar referencia del triangulo --> respecto
                % vertical marcador verde
                % ángulo - movimiento hacia fuera, ángulo + movimiento hacia dentro
                distanciaBrazo= app.CMunecaR2(i,:)-app.CHombro2(i,:);
                disBrazoX=(distanciaBrazo(1));
                disBrazoY=(distanciaBrazo(2));
                
                puntoInterseccion = [app.CHombro2(1:end,1),app.CMunecaR2(1:end,2)];
                
                distanciaHombro_Torso= puntoInterseccion(i,:)-app.CHombro2(i,:);
                disHombro_TorsoX=(distanciaHombro_Torso(1));
                disHombro_TorsoY=(distanciaHombro_Torso(2));
                
                distanciaMuneca_Torso= puntoInterseccion(i,:)-app.CMunecaR2(i,:);
                disMuneca_TorsoX=(distanciaMuneca_Torso(1));
                disMuneca_TorsoY=(distanciaMuneca_Torso(2));
                
                %Cálculamos las distancias del triángulo escaleno
                brazo=sqrt((disBrazoX^2)+ (disBrazoY^2));
                hombro_torso=sqrt((disHombro_TorsoX^2)+ (disHombro_TorsoY^2));
                muneca_torso=sqrt((disMuneca_TorsoX^2)+ (disMuneca_TorsoY^2));
                
                %Una vez que hemos construido el triángulo conociendo sus distancia ,
                %calcularemos el ángulo de abduccion-aduccion que forma el hombro con el
                %teorema del coseno ----> a^2=b^2+c^2-2*b*a*cos(ang), despejando coseno del
                %angulo nos queda lo siguiente a^2-b^^-c^
                tCoseno1=((muneca_torso^2-hombro_torso^2-brazo^2)/((-2)*(hombro_torso*brazo)));%siendo a=codo_cadera,b=tronco,c=brazo)
                
                %Hemos obtenido el cos(ang) para extraer el ang debemos hacer la
                %arccoseno
                arcHombro= acos(tCoseno1);
                %El ángulo nos lo dan en radianes y hay que pasarlo a grados para su uso
                angHY= rad2deg(arcHombro);
                app.AHombroYawFinal=[app.AHombroYawFinal,angHY];%Concatena todos los ángulos del movimiento (ángulos totales)
                
                
            end
            
            AHombroYawFinal1= (app.AHombroYawFinal(1,1:120))*(-1);
            AHombroYawFinal1_1=(app.AHombroYawFinal(1,121:166))*(-1);
            AHombroYawFinal1_2=(AHombroYawFinal1_1)+15;
            AHombroYawFinal2=(app.AHombroYawFinal(1,375:510))*(-1);
            AHombroYawFinal3=(app.AHombroYawFinal(1,167:200))-15;
            AHombroYawFinal3_1=(app.AHombroYawFinal(1,201:375));
            app.AHombroYawFinal0=[AHombroYawFinal1,AHombroYawFinal1_2,AHombroYawFinal3,AHombroYawFinal3_1,AHombroYawFinal2];
            
        end
        
        % Esta función permite la reproducción del vídeo siempre que la
        % variable simulat sea true.
        function simulatTrue(app)
            while(app.simulatMarcadores)
                for i=1:app.v.numFrames
                    
                    % Comprobamos qué plano de grabación ha sido
                    % seleccionado para poder aplicar una técnica de
                    % detección de marcadores u otra
                    selectedButton = app.ButtonGroup_2.SelectedObject;
                    
                    switch selectedButton
                        
                        case app.PlanoSagitalButton
                            planoSagital(app,i);
                            
                        case app.PlanoFrontalButton
                            planoFrontal(app,i);
                    end
                    
                    if app.simulatMarcadores== false
                        break
                    end
                    
                    if (i == app.v.numFrames)
                        app.simulatMarcadores = false;
                    end
                    
                end
            end
        end
    end%methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ANGULOSPanel.Visible='on';
            app.SIMULACIONPanel.Visible='on';
            app.Button_6.Visible='on';
            app.USUARIOPanel.Visible='on';
            app.Image.Visible='on';
            app.ELECTROMIOGRAMAPanel.Visible='off';
            app.Panel.Visible='on';
            app.CALIBRACINDELSENSOREMGPanel.Visible='off';
            app.PROCESADODEVDEOPanel.Visible='off';
            app.ButtonGroup_2.Visible='off';
            app.NombredearchivoEditField.Visible= 'off';
            app.ANGULOSPanel_2.Visible= 'off';
            app.SIMULACIONPanel_2.Visible= 'off';
            app.NombredearchivoEditFieldLabel.Visible= 'off';
            
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            
            app.simulat=true;%Actualizo el valor de mi variable, para comenzar la sgte función:
            graph_true(app);
            
        end

        % Button pushed function: PAUSEButton
        function PAUSEButtonPushed(app, event)
            
            app.simulat= false;
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.simulat=false;
            cla(app.UIAxes);
            app.Slider.Value=0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %Creamos un informe biomecánico a través de los datos generados por el usuario.
            app.simulat=true;
            graph_true(app);
            %OPCIÓN SIMULACIÓN
            if app.DropDown.Value == "Simulación"
                
                app.VentanaInform = VentanaInforme16NOV;
                valoractual=app.Slider.Value;
                func(app.VentanaInform,app.NOMBREEditField.Value,app.EDADEditField.Value,app.yqHY,app.yqHP,app.yqHR, app.yqCY,app.yqCR,valoractual);
                graficaInforme(app.VentanaInform);%Las funciones de informeventana son public por eso se pueden usar.
                index=round(valoractual);
                dibujaENinforme(app.VentanaInform,index);
                setSlider(app.VentanaInform,index);
                
                %OPCIÓN EMG
            elseif app.DropDown.Value == "Electromiograma"
                app.VentanaInformEMG = InformeEMG;
                %Calculo el umbral de  activacion muscular
                umbral=app.EditField_2.Value + 0.25*((app.EditField.Value-app.EditField_2.Value));
                func(app.VentanaInformEMG,app.NOMBREEditField.Value,app.EDADEditField.Value, app.EditField.Value,app.EditField_2.Value,umbral,app.TiemposegEditField.Value,app.senal_final);
                graficaEMG(app.VentanaInformEMG);
                
                %OPCIÓN IMUS
            elseif app.DropDown.Value== "IMUS"
                app.VentanaIMUS = VentanaInformeIMUS;
                func(app.VentanaIMUS,app.NOMBREEditField.Value,app.EDADEditField.Value,app.imuCY,app.imuCR,app.imuHY, app.imuHP,app.imuHR);
                graficaIMUS(app.VentanaIMUS);%Las funciones de informeventana son public por eso se pueden usar.
                
                %OPCIÓN MARCADORES
            elseif app.DropDown.Value == "Marcadores"
                
                app.VentanaMarcadores = VentanaInformeMarcadores;
                valoractual=app.Slider_2.Value;
                func(app.VentanaMarcadores,app.NOMBREEditField.Value,app.EDADEditField.Value,app.yqHR,app.yqHP, app.yqCY,app.yqCR,app.envMeanH,app.envMeanH2,app.ACodoRollFinal,app.envMeanC,valoractual);
                graficaMarcadores(app.VentanaMarcadores);%Las funciones de informeventana son public por eso se pueden usar.
                index=round(valoractual);
                dibujaMarcadores(app.VentanaMarcadores,index);
                setSlider(app.VentanaMarcadores,index);
                
                
            end
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %Abre un informe biomecánico de un movimiento simulado guardado
            %anteriormente
            
            %Establece el tipo de informe que desea y abre la ventana Abrir informe:
            TipoInforme(AbrirInforme,app.DropDown.Value);
            %Nota: El usuario puede cambiarlo en la ventana AbrirInforme
            %nuevamente si así lo desea(pero le ahorramos trabajo de esta
            %forma).
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.value = app.Slider.Value;%Valor que señala el slider
            eje=app.UIAxes;
            index=round(app.value);%Necesario ya que nuestro pulso no es perfecto al elegir un valor entero.
            
            if index ~=0
                cla(app.UIAxes);%Borramos la figura que está en ese momento
                dibujaBrazo(app,index,eje);%Y dibujamos la nueva solcitada.
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.valSwi = app.Switch.Value;
            if app.valSwi== 'I'
                app.ACy=app.acy1;
                app.ACr=app.acr1;
                app.AHy=app.ahy1;
                app.AHp=app.ahp1;
                app.AHr=app.ahr1;
            end
            
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch selectedButton
                
                case app.SimulacinButton
                    app.ANGULOSPanel.Visible='on';
                    app.Switch.Visible='on';
                    app.Slider.Visible='on';
                    app.SIMULACIONPanel.Visible='on';
                    app.Button_6.Visible='on';
                    app.USUARIOPanel.Visible='on';
                    app.Image.Visible='on';
                    app.ELECTROMIOGRAMAPanel.Visible='off';
                    app.Panel.Visible='on';
                    app.CALIBRACINDELSENSOREMGPanel.Visible='off';
                    app.PLAYButton.Visible='on';
                    app.PAUSEButton.Visible='on';
                    app.STOPButton.Visible='on';
                    cla(app.UIAxes);
                    app.MODOREALLabel.Visible='off';
                    app.MODOVIRTUALLabel.Visible='on';
                    app.GRAFICARButton_2.Visible='off';
                    app.GRAFICARButton_2.Visible='on';
                    cla(app.UIAxes2);
                    cla(app.UIAxes3);
                    app.PAUSEButton_2.Visible='off';
                    app.STOPButton_3.Visible='off';
                    app.PLAYButton_2.Visible='off';
                    app.PROCESADODEVDEOPanel.Visible='off';
                    app.ANGULOSPanel_2.Visible= 'off';
                    app.SIMULACIONPanel_2.Visible= 'off';
                    app.ButtonGroup_2.Visible='off';
                    app.NombredearchivoEditField.Visible= 'off';
                    app.NombredearchivoEditFieldLabel.Visible= 'off';
                    
                case app.EMGButton
                    app.ANGULOSPanel.Visible='off';
                    app.SIMULACIONPanel.Visible='off';
                    app.Button_6.Visible='on';
                    app.USUARIOPanel.Visible='on';
                    app.Image.Visible='on';
                    app.ELECTROMIOGRAMAPanel.Visible='on';
                    app.Panel.Visible='on';
                    app.CALIBRACINDELSENSOREMGPanel.Visible='on';
                    app.MODOREALLabel.Visible='off';
                    app.MODOVIRTUALLabel.Visible='off';
                    app.PROCESADODEVDEOPanel.Visible='off';
                    app.ANGULOSPanel_2.Visible= 'off';
                    app.SIMULACIONPanel_2.Visible= 'off';
                    app.ButtonGroup_2.Visible='off';
                    app.NombredearchivoEditField.Visible= 'off';
                    app.NombredearchivoEditFieldLabel.Visible= 'off';
                    
                case app.SensoresInercialesButton
                    app.ANGULOSPanel.Visible='on';
                    app.SIMULACIONPanel.Visible='on';
                    app.Button_6.Visible='on';
                    app.USUARIOPanel.Visible='on';
                    app.Image.Visible='on';
                    app.Panel.Visible='on';
                    app.ANGULOSPanel.Visible='on';
                    app.ELECTROMIOGRAMAPanel.Visible='off';
                    app.CALIBRACINDELSENSOREMGPanel.Visible='off';
                    app.Switch.Visible='off';
                    app.Slider.Visible='off';
                    app.PLAYButton.Visible='off';
                    app.PAUSEButton.Visible='off';
                    app.STOPButton.Visible='off';
                    cla(app.UIAxes);
                    cla(app.UIAxes2);
                    cla(app.UIAxes3);
                    app.MODOREALLabel.Visible='on';
                    app.MODOVIRTUALLabel.Visible='off';
                    app.GRAFICARButton_2.Visible='on';
                    app.PAUSEButton_2.Visible='on';
                    app.STOPButton_3.Visible='on';
                    app.PLAYButton_2.Visible='on';
                    app.PROCESADODEVDEOPanel.Visible='off';
                    app.ANGULOSPanel_2.Visible= 'off';
                    app.SIMULACIONPanel_2.Visible= 'off';
                    app.ButtonGroup_2.Visible='off';
                    app.NombredearchivoEditField.Visible= 'off';
                    app.NombredearchivoEditFieldLabel.Visible= 'off';
                    
                case app.MarcadoresvisualesButton
                    app.ANGULOSPanel.Visible='off';
                    app.SIMULACIONPanel.Visible='off';
                    app.Button_6.Visible='on';
                    app.USUARIOPanel.Visible='on';
                    app.Image.Visible='on';
                    app.ELECTROMIOGRAMAPanel.Visible='off';
                    app.Panel.Visible='on';
                    app.CALIBRACINDELSENSOREMGPanel.Visible='off';
                    app.MODOREALLabel.Visible='off';
                    app.MODOVIRTUALLabel.Visible='off';
                    app.PROCESADODEVDEOPanel.Visible='on';
                    app.ANGULOSPanel_2.Visible= 'on';
                    app.SIMULACIONPanel_2.Visible= 'on';
                    app.ButtonGroup_2.Visible='on';
                    app.NombredearchivoEditField.Visible= 'on';
                    app.NombredearchivoEditFieldLabel.Visible= 'on';
                    app.seleccionarVideo.Visible= 'on';
                    app.UIAxes_2.Visible = 'off';
                    app.PLAYButton_4.Enable = 'off';
                    app.PAUSEButton_4.Enable = 'off';
                    app.STOPButton_5.Enable = 'off';
                    app.GRAFICARButton_4.Enable = 'off';
            end
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)
            app.boolcalibracion=false;
            ejes=app.UIAxes4;
            time=app.TiemposegEditField.Value;
            emg(app,ejes,time);
        end

        % Button pushed function: MXIMAButton
        function MXIMAButtonPushed(app, event)
            app.boolcalibracion=true;
            ejes=app.UIAxes4_2;
            time=5;
            app.Lamp.Color='yellow';
            vectorMax=emg(app,ejes,time);
            ValMax=mean(vectorMax);
            app.Lamp.Color='green';
            app.EditField.Value=ValMax;
            
        end

        % Button pushed function: MNIMAButton
        function MNIMAButtonPushed(app, event)
            app.boolcalibracion=true;
            ejes=app.UIAxes4_2;
            time=5;
            app.Lamp_2.Color='yellow';
            vectorMin=emg(app,ejes,time);
            ValMin=mean(vectorMin);
            app.Lamp_2.Color='green';
            app.EditField_2.Value=ValMin;
            
        end

        % Callback function
        function MEDIRButtonPushed(app, event)
            app.simulatIMU= true;
            eje=app.UIAxes;
            readIMUgraph(app,eje);
        end

        % Callback function
        function STOPButton_2Pushed(app, event)
            app.simulatIMU= false;
        end

        % Button pushed function: GRAFICARButton_2
        function GRAFICARButton_2Pushed(app, event)
            %ÁNGULOS CODO
            %Grado de libertad yaw
            X=[1:size(app.YPRfc)];
            plot(app.UIAxes3, X,app.YPRfc(:,1),'b-')
            hold (app.UIAxes3, 'on')
            
            %Grado de libertad roll
            plot(app.UIAxes3,X,app.YPRfc(:,3),'r-')
            legend(app.UIAxes3,'Flexoextensión real','Pronosupinación real');
            hold (app.UIAxes3, 'off')
            
            %ÁNGULOS HOMBRO
            %Grado de libertad pitch
            plot(app.UIAxes2,X,app.YPRfh(:,2),'b-')
            hold (app.UIAxes2, 'on')
            
            %Grado de libertad yaw
            plot(app.UIAxes2,X,app.YPRfc(:,1),'r-')
            
            %Grado de libertad roll
            plot(app.UIAxes2,X,app.YPRfc(:,3),'g-')
            legend(app.UIAxes2,'Flexoextensión real','Abducción-Aducción real','Rotación real');
            hold (app.UIAxes2, 'off')
        end

        % Button pushed function: PLAYButton_2
        function PLAYButton_2Pushed(app, event)
            app.simulatIMU= true;
            eje=app.UIAxes;
            readIMUgraph(app,eje);
        end

        % Button pushed function: PAUSEButton_2
        function PAUSEButton_2Pushed(app, event)
            app.simulatIMU= false;
        end

        % Button pushed function: STOPButton_3
        function STOPButton_3Pushed(app, event)
            app.simulatIMU= false;
            cla(app.UIAxes);
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
        end

        % Button pushed function: INICIARDETECCINDEMARCADORESButton
        function INICIARDETECCINDEMARCADORESButtonPushed(app, event)
            app.GRAFICARButton_4.Enable = 'off';
            app.PlanoSagitalButton.Enable = 'on';
            app.PlanoFrontalButton.Enable = 'on';
            app.PLAYButton_3.Enable = 'on';
            app.FotogramasSlider.Enable = 'on';
            app.PAUSEButton_3.Enable = 'off';
            app.STOPButton_4.Enable = 'off';
            app.SehabilitarnlasfuncionesdedeteccindemarcadoresunaLabel.Visible = 'off';
            app.VelocidaddereproduccinEditField.Visible = 'on';
            app.VelocidaddereproduccinfotogramassegMximo20fpsLabel.Visible = 'on';
            app.Label_2.Visible = 'on';
        end

        % Button pushed function: PLAYButton_3
        function PLAYButton_3Pushed(app, event)
            app.INICIARDETECCINDEMARCADORESButton.Visible = 'off';
            app.GRAFICARButton_4.Enable = 'off';
            app.simulatMarcadores=true;
            simulatTrue(app);
            app.PAUSEButton_3.Enable = 'on';
            app.STOPButton_4.Enable = 'on';
            app.GRAFICARButton_4.Enable = 'on';
        end

        % Button pushed function: PAUSEButton_3
        function PAUSEButton_3Pushed(app, event)
            
            app.FotogramasSlider.Enable = 'on';
            app.simulatMarcadores= false;
            
        end

        % Button pushed function: STOPButton_4
        function STOPButton_4Pushed(app, event)
            app.simulatMarcadores=false;
            cla(app.UIAxes_2);
            app.FotogramasSlider.Enable = 'on';
            app.FotogramasSlider.Value = 0;
        end

        % Value changing function: FotogramasSlider
        function FotogramasSliderValueChanging(app, event)
            
            changingValue = event.Value; % Obtenemos el valor de la posición del Slider pulsada
            if (changingValue == 0)
                nuevoFotograma = 1;
            else
                nuevoFotograma = round(changingValue,0); % Redondeamos a un número entero
            end
            
            selectedButton = app.ButtonGroup_2.SelectedObject; % Comprobamos de nuevo qué plano de grabación está seleccionado
            app.simulatMarcadores = false; % No queremos reproducción automática, sino que muestre el fotograma seleccionado
            
            cla(app.UIAxes_2); % Limpiamos el gráfico
            
            % En función de qué plano de grabación está seleccionado,
            % ejecutamos una función u otra
            switch selectedButton
                case app.PlanoSagitalButton
                    planoSagital(app,nuevoFotograma);
                case app.PlanoFrontalButton
                    planoFrontal(app,nuevoFotograma);
            end
            
        end

        % Button pushed function: seleccionarVideo
        function seleccionarvideo(app, event)
            
            % CARGAR VIDEO
            [filename pathname] = uigetfile({'*.mp4'}, 'File Selector');
            
            fullpathname = strcat(pathname,filename);
            text = fileread(fullpathname);
            
            app.NombredearchivoEditField.Value = filename;
            app.v = VideoReader(filename);
            
            app.PLAYButton_3.Enable = 'off';
            app.PAUSEButton_3.Enable = 'off';
            app.STOPButton_4.Enable = 'off';
            app.INICIARDETECCINDEMARCADORESButton.Visible = 'on';
            app.INICIARDETECCINDEMARCADORESButton.Enable = 'off';
            app.VelocidaddereproduccinEditField.Editable = 'off';
            app.FotogramasSlider.Enable = 'off';
            app.FotogramasSlider.Limits = [0,app.v.numFrames];
            imshow(readFrame(app.v), 'Parent', app.UIAxes_2);
            
            app.SehabilitarnlasfuncionesdedeteccindemarcadoresunaLabel.Visible = 'off';
            app.VelocidaddereproduccinfotogramassegMximo20fpsLabel.Visible = 'on';
            app.Label_2.Visible = 'on';
            app.INICIARDETECCINDEMARCADORESButton.Enable = 'on';
            app.VelocidaddereproduccinEditField.Visible = 'on';
            app.VelocidaddereproduccinEditField.Editable = 'on';
            
        end

        % Button pushed function: GRAFICARButton, GRAFICARButton_4
        function graficarAngMarcadores(app, event)
            
            cla(app.UIAxes2_2);
            cla(app.UIAxes3_2);
            
            calculoAngSagital(app);
            calculoAngFrontal(app);
            
            %ÁNGULOS CODO
            %Grado de libertad yaw
            ACodoPitchFinal = app.angCodoPitchFinal(1:510);
            [envHigh1, envLow1] = envelope(ACodoPitchFinal,16,'peak');
            app.envMeanC = (envHigh1+envLow1)/2;
            plot(app.UIAxes3_2, app.envMeanC,'b')
            hold (app.UIAxes3_2, 'on')
            
            %Grado de libertad roll
            app.angCodoRollFinal = app.ACodoRollFinal(1:510);
            plot(app.UIAxes3_2,app.angCodoRollFinal,'r')
            legend(app.UIAxes3_2,'Flexoextensión','Pronosupinación');
            hold (app.UIAxes3_2, 'off')
            
            %ÁNGULOS HOMBRO
            %Grado de libertad pitch
            AHombroPitchFinal = app.angHombroPitchFinal(1:510);
            [envHigh2, envLow2] = envelope(AHombroPitchFinal,16,'peak');
            app.envMeanH = (envHigh2+envLow2)/2;
            plot(app.UIAxes2_2,app.envMeanH,'b')
            hold (app.UIAxes2_2, 'on')
            
            %Grado de libertad yaw
            angHombroYawFinal = app.AHombroYawFinal0(1:510);
            [envHigh3, envLow3] = envelope(angHombroYawFinal,16,'peak');
            app.envMeanH2 = (envHigh3+envLow3)/2;
            plot(app.UIAxes2_2,app.envMeanH2,'r')
            legend(app.UIAxes2_2,'Flexoextensión','Abducción-Aducción');
            hold (app.UIAxes2_2, 'off')
            
            app.PLAYButton_4.Enable = 'on';
            app.PAUSEButton_4.Enable = 'on';
            app.STOPButton_5.Enable = 'on';
            
        end

        % Button pushed function: PLAYButton_4
        function playSimulacion(app, event)
            app.simulat4=true;%Actualizo el valor de mi variable, para comenzar la sgte función:
            graph_true_video(app);
        end

        % Button pushed function: PAUSEButton_4
        function pauseSimulacion(app, event)
            app.simulat4= false;
        end

        % Button pushed function: STOPButton_5
        function stopSimulacion(app, event)
            app.simulat4=false;
            cla(app.UIAxes_3);
            eje=app.UIAxes_3;
            % FIGURA INICIAL
            rotate3d(eje,'on');%Posibilidad de girar los ejes con el ratón
            view(eje);
            axis(eje,'equal','on');
            
            %DIBUJAR PRISMAS
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5;2 3 7 6;3 4 8 7; 4 1 5 8];%Orden de los vértices para formar cada una de las caras del prisma
            
            %GRAFICAR LOS PRISMAS
            patch(eje,'vertices',app.shoulder,'faces',f,'FaceVertexCData',40, 'FaceColor', 'flat')
            hold(eje,'on');
            axis(eje,'equal','on');
            patch(eje,'vertices',app.elbow,'faces',f, 'FaceVertexCData', 60, 'FaceColor', 'flat')
            patch(eje,'vertices',app.wrist,'faces',f, 'FaceVertexCData', 140, 'FaceColor', 'flat')
            
            %DIBUJAR ESFERAS
            [X,Y,Z] = sphere;%Esferas
            
            %Multiplicamos por 10 para que se vean mejor
            X=X*10;
            Y=Y*10;
            Z=Z*10;
            
            %Graficamos las esferas adyacentes a cada uno de los prismas
            S1 = surf(eje, X+sum(app.shoulder(1:4,1))/4,Y+sum(app.shoulder(1:4,2))/4,Z+sum(app.shoulder(1:4,3))/4);
            S2 = surf(eje, X+sum(app.elbow(1:4,1))/4,Y+sum(app.elbow(1:4,2))/4,Z+sum(app.elbow(1:4,3))/4);
            S3 = surf(eje, X+sum(app.wrist(1:4,1))/4,Y+sum(app.wrist(1:4,2))/4,Z+sum(app.wrist(1:4,3))/4);
            hold(eje,'off');
            
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            
            app.valor = app.Slider_2.Value;
            eje=app.UIAxes_3;
            index=round(app.valor);%Necesario ya que nuestro pulso no es perfecto al elegir un valor entero.
            
            if index ~=0
                cla(app.UIAxes_3);%Borramos la figura que está en ese momento
                dibujaBrazo_video(app,index,eje);%Y dibujamos la nueva solcitada.
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ANGULOSPanel = uipanel(app.UIFigure);
            app.ANGULOSPanel.Title = 'ÁNGULOS DE LAS ARTICULACIONES';
            app.UIAxes2 = uiaxes(app.ANGULOSPanel);
            title(app.UIAxes2, 'Hombro')
            xlabel(app.UIAxes2, '%Ciclo')
            ylabel(app.UIAxes2, 'Grados')
            app.UIAxes3 = uiaxes(app.ANGULOSPanel);
            title(app.UIAxes3, 'Codo')
            xlabel(app.UIAxes3, '%Ciclo')
            ylabel(app.UIAxes3, 'Grados')
            app.GRAFICARButton = uibutton(app.ANGULOSPanel, 'push');
            app.GRAFICARButton.ButtonPushedFcn = createCallbackFcn(app, @graficarAngMarcadores, true);
            app.GRAFICARButton.Tooltip = {'Grafica los ángulos generados por las articulaciones en un ciclo. '; 'Ciclo: Movimiento de servir agua de una jarra. '};
            app.GRAFICARButton.Text = 'GRAFICAR';
            app.GRAFICARButton_2 = uibutton(app.ANGULOSPanel, 'push');
            app.GRAFICARButton_2.ButtonPushedFcn = createCallbackFcn(app, @GRAFICARButton_2Pushed, true);
            app.GRAFICARButton_2.Tooltip = {'Grafica los ángulos generados por las articulaciones en un ciclo. '; 'Ciclo: Movimiento de servir agua de una jarra. '};
            app.GRAFICARButton_2.Text = 'GRAFICAR';
            app.SIMULACIONPanel = uipanel(app.UIFigure);
            app.SIMULACIONPanel.Title = 'SIMULACIÓN  DE LA EXTREMIDAD SUPERIOR';
            app.UIAxes = uiaxes(app.SIMULACIONPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-20 60];
            app.UIAxes.YLim = [-100 40];
            app.UIAxes.ZLim = [-100 100];
            app.UIAxes.XTick = [-20 0 20 40 60 80 100];
            app.UIAxes.XTickLabel = {'-20'; '0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes.YTick = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.Switch = uiswitch(app.SIMULACIONPanel, 'slider');
            app.Switch.Items = {'D', 'I'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'D';
            app.Slider = uislider(app.SIMULACIONPanel);
            app.Slider.Limits = [0 510];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.PLAYButton = uibutton(app.SIMULACIONPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Icon = 'simbolo-play-png-.png';
            app.PLAYButton.Tooltip = {'Comienza la simulación gráfica de la extremidad superior durante el movimiento de servir agua de una jarra en un vaso.'; 'Posición inicial: Sentadx con las brazos estirados sobre la mesa.'};
            app.PLAYButton.Text = '';
            app.PAUSEButton = uibutton(app.SIMULACIONPanel, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButtonPushed, true);
            app.PAUSEButton.Icon = '80c52538116b504f0d45858a707d0c4f-bot--n-de-pausa-plana-by-vexels.png';
            app.PAUSEButton.Text = '';
            app.STOPButton = uibutton(app.SIMULACIONPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Icon = 'stop-button-512.png';
            app.STOPButton.Text = '';
            app.MODOVIRTUALLabel = uilabel(app.SIMULACIONPanel);
            app.MODOVIRTUALLabel.Text = 'MODO VIRTUAL';
            app.MODOREALLabel = uilabel(app.SIMULACIONPanel);
            app.MODOREALLabel.Visible = 'off';
            app.MODOREALLabel.Tooltip = {'Los datos son obtenidos mediante sensores inerciales. Conecte dichos sensores en el hombro y codo.'; 'NOTA:'; 'Es importante que los sensores estén calibrados antes de colocarlos en el sujeto. '};
            app.MODOREALLabel.Text = 'MODO REAL';
            app.PLAYButton_2 = uibutton(app.SIMULACIONPanel, 'push');
            app.PLAYButton_2.ButtonPushedFcn = createCallbackFcn(app, @PLAYButton_2Pushed, true);
            app.PLAYButton_2.Icon = 'simbolo-play-png-.png';
            app.PLAYButton_2.Visible = 'off';
            app.PLAYButton_2.Tooltip = {'Comienza la simulación gráfica de la extremidad superior durante el movimiento de servir agua de una jarra en un vaso.'; 'Posición inicial: Sentadx con las brazos estirados sobre la mesa.'};
            app.PLAYButton_2.Text = '';
            app.PAUSEButton_2 = uibutton(app.SIMULACIONPanel, 'push');
            app.PAUSEButton_2.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButton_2Pushed, true);
            app.PAUSEButton_2.Icon = '80c52538116b504f0d45858a707d0c4f-bot--n-de-pausa-plana-by-vexels.png';
            app.PAUSEButton_2.Visible = 'off';
            app.PAUSEButton_2.Text = '';
            app.STOPButton_3 = uibutton(app.SIMULACIONPanel, 'push');
            app.STOPButton_3.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_3Pushed, true);
            app.STOPButton_3.Icon = 'stop-button-512.png';
            app.STOPButton_3.Visible = 'off';
            app.STOPButton_3.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.USUARIOPanel = uipanel(app.UIFigure);
            app.USUARIOPanel.Title = 'USUARIO';
            app.Image_2 = uiimage(app.USUARIOPanel);
            app.Image_2.ImageSource = '538846-200.png';
            app.NOMBREEditFieldLabel = uilabel(app.USUARIOPanel);
            app.NOMBREEditFieldLabel.Text = '  NOMBRE  ';
            app.NOMBREEditField = uieditfield(app.USUARIOPanel, 'text');
            app.EDADEditFieldLabel = uilabel(app.USUARIOPanel);
            app.EDADEditFieldLabel.Text = '    EDAD    ';
            app.EDADEditField = uieditfield(app.USUARIOPanel, 'text');
            app.Button_3 = uibutton(app.USUARIOPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = '16036.png';
            app.Button_3.Tooltip = {'Abre un informe biomecánico de la simulación del movimiento, guardado anteriormente.'};
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.USUARIOPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'ICONOINFORME.png';
            app.Button_2.Tooltip = {'Crea un informe biomecánico de la simulación del movimiento.'};
            app.Button_2.Text = '';
            app.DropDown = uidropdown(app.USUARIOPanel);
            app.DropDown.Items = {'Simulación', 'Electromiograma', 'IMUS', 'Marcadores'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Simulación';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Icono provisional.png';
            app.ELECTROMIOGRAMAPanel = uipanel(app.UIFigure);
            app.ELECTROMIOGRAMAPanel.Title = 'ELECTROMIOGRAMA';
            app.UIAxes4 = uiaxes(app.ELECTROMIOGRAMAPanel);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.CONECTARButton = uibutton(app.ELECTROMIOGRAMAPanel, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Tooltip = {'Conecte el sensor EMG para la toma de datos. Colóquelo en el bísceps.'; 'Para ello debe haber realizado la calibración máxima y mínima  e introducido el tiempo en segundos durante el cual quiere realizar la medición. '};
            app.CONECTARButton.Text = 'CONECTAR';
            app.TiemposegEditFieldLabel = uilabel(app.ELECTROMIOGRAMAPanel);
            app.TiemposegEditFieldLabel.Text = 'Tiempo (seg) ';
            app.TiemposegEditField = uieditfield(app.ELECTROMIOGRAMAPanel, 'numeric');
            app.TiemposegEditField.Tooltip = {'Introduzca la duración en segundos para la cual desea realizar el electromiograma.'};
            app.Panel = uipanel(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SimulacinButton = uiradiobutton(app.ButtonGroup);
            app.SimulacinButton.Text = 'Simulación';
            app.SimulacinButton.Value = true;
            app.EMGButton = uiradiobutton(app.ButtonGroup);
            app.EMGButton.Text = 'EMG';
            app.SensoresInercialesButton = uiradiobutton(app.ButtonGroup);
            app.SensoresInercialesButton.Text = 'Sensores Inerciales';
            app.MarcadoresvisualesButton = uiradiobutton(app.ButtonGroup);
            app.MarcadoresvisualesButton.Text = 'Marcadores visuales';
            app.CALIBRACINDELSENSOREMGPanel = uipanel(app.UIFigure);
            app.CALIBRACINDELSENSOREMGPanel.Title = 'CALIBRACIÓN DEL SENSOR EMG';
            app.UIAxes4_2 = uiaxes(app.CALIBRACINDELSENSOREMGPanel);
            title(app.UIAxes4_2, '')
            xlabel(app.UIAxes4_2, '')
            ylabel(app.UIAxes4_2, '')
            app.UIAxes4_2.YTick = [0 0.5 1];
            app.MXIMAButton = uibutton(app.CALIBRACINDELSENSOREMGPanel, 'push');
            app.MXIMAButton.ButtonPushedFcn = createCallbackFcn(app, @MXIMAButtonPushed, true);
            app.MXIMAButton.Tooltip = {'Conecte el sensor al sistema para comenzar la calibración de su actividad muscular máxima. Para ello debe hacer el máximo esfuerzo durante 10 segundos en el bísceps.'};
            app.MXIMAButton.Text = 'MÁXIMA';
            app.MNIMAButton = uibutton(app.CALIBRACINDELSENSOREMGPanel, 'push');
            app.MNIMAButton.ButtonPushedFcn = createCallbackFcn(app, @MNIMAButtonPushed, true);
            app.MNIMAButton.Tooltip = {'Realice los mismos pasos de la calibración máxima, sin embargo, en este caso debe estar el músculo en reposo.'};
            app.MNIMAButton.Text = 'MÍNIMA';
            app.Lamp = uilamp(app.CALIBRACINDELSENSOREMGPanel);
            app.EditField = uieditfield(app.CALIBRACINDELSENSOREMGPanel, 'numeric');
            app.Lamp_2 = uilamp(app.CALIBRACINDELSENSOREMGPanel);
            app.EditField_2 = uieditfield(app.CALIBRACINDELSENSOREMGPanel, 'numeric');
            app.PROCESADODEVDEOPanel = uipanel(app.UIFigure);
            app.PROCESADODEVDEOPanel.Title = 'PROCESADO DE VÍDEO';
            app.VelocidaddereproduccinEditField = uieditfield(app.PROCESADODEVDEOPanel, 'numeric');
            app.VelocidaddereproduccinEditField.Visible = 'off';
            app.VelocidaddereproduccinEditField.Tooltip = {'Introduzca la velocidad de reproducción del vídeo procesado.'};
            app.VelocidaddereproduccinEditField.Value = 20;
            app.INICIARDETECCINDEMARCADORESButton = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.INICIARDETECCINDEMARCADORESButton.ButtonPushedFcn = createCallbackFcn(app, @INICIARDETECCINDEMARCADORESButtonPushed, true);
            app.INICIARDETECCINDEMARCADORESButton.Tooltip = {'Permite el acceso al procesado de vídeo, activando los botones de reproducción, pausa y stop.'};
            app.INICIARDETECCINDEMARCADORESButton.Text = 'INICIAR DETECCIÓN DE MARCADORES';
            app.FotogramasSliderLabel = uilabel(app.PROCESADODEVDEOPanel);
            app.FotogramasSliderLabel.Text = 'Fotogramas';
            app.FotogramasSlider = uislider(app.PROCESADODEVDEOPanel);
            app.FotogramasSlider.Limits = [0 510];
            app.FotogramasSlider.ValueChangingFcn = createCallbackFcn(app, @FotogramasSliderValueChanging, true);
            app.FotogramasSlider.Tooltip = {'Permite seleccionar el fotograma que se desea ver.'};
            app.PLAYButton_3 = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.PLAYButton_3.ButtonPushedFcn = createCallbackFcn(app, @PLAYButton_3Pushed, true);
            app.PLAYButton_3.Icon = 'simbolo-play-png-.png';
            app.PLAYButton_3.Tooltip = {'Comienza la reproducción del vídeo con la detección de marcadores.'};
            app.PLAYButton_3.Text = '';
            app.PAUSEButton_3 = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.PAUSEButton_3.ButtonPushedFcn = createCallbackFcn(app, @PAUSEButton_3Pushed, true);
            app.PAUSEButton_3.Icon = '80c52538116b504f0d45858a707d0c4f-bot--n-de-pausa-plana-by-vexels.png';
            app.PAUSEButton_3.Tooltip = {'Pausa la reproducción del vídeo.'};
            app.PAUSEButton_3.Text = '';
            app.STOPButton_4 = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.STOPButton_4.ButtonPushedFcn = createCallbackFcn(app, @STOPButton_4Pushed, true);
            app.STOPButton_4.Icon = 'stop-button-512.png';
            app.STOPButton_4.Tooltip = {'Para la reproducción y vuelve al inicio.'};
            app.STOPButton_4.Text = '';
            app.UIAxes_2 = uiaxes(app.PROCESADODEVDEOPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.SehabilitarnlasfuncionesdedeteccindemarcadoresunaLabel = uilabel(app.PROCESADODEVDEOPanel);
            app.SehabilitarnlasfuncionesdedeteccindemarcadoresunaLabel.Text = {'Se habilitarán las funciones de detección de marcadores una vez termine la  '; 'reproducción del video cargado. Asegúrese de que ha seleccionado el'; 'plano de grabación correcto antes de iniciar la detección de marcadores.'};
            app.VelocidaddereproduccinfotogramassegMximo20fpsLabel = uilabel(app.PROCESADODEVDEOPanel);
            app.VelocidaddereproduccinfotogramassegMximo20fpsLabel.Visible = 'off';
            app.VelocidaddereproduccinfotogramassegMximo20fpsLabel.Text = {'Velocidad de reproducción '; '(fotogramas/seg). Máximo 20fps.'};
            app.Label_2 = uilabel(app.PROCESADODEVDEOPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = {'Elija la velocidad de reproducción '; 'del vídeo antes de darle al play.'};
            app.SIMULACIONPanel_2 = uipanel(app.UIFigure);
            app.SIMULACIONPanel_2.Title = 'SIMULACIÓN EN AVATAR 3D';
            app.UIAxes_3 = uiaxes(app.SIMULACIONPanel_2);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_3.XLim = [-20 60];
            app.UIAxes_3.YLim = [-100 40];
            app.UIAxes_3.ZLim = [-100 100];
            app.UIAxes_3.XTick = [-40 0 20 40 60 80 100];
            app.UIAxes_3.XTickLabel = {'-20'; '0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes_3.YTick = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.PLAYButton_4 = uibutton(app.SIMULACIONPanel_2, 'push');
            app.PLAYButton_4.ButtonPushedFcn = createCallbackFcn(app, @playSimulacion, true);
            app.PLAYButton_4.Icon = 'simbolo-play-png-.png';
            app.PLAYButton_4.Tooltip = {'Comienza la simulación gráfica de la extremidad superior durante el movimiento de servir agua de una jarra en un vaso. Hacerlo cuando ya se hayan graficado los ángulos.'; 'Posición inicial: Sentadx con las brazos estirados sobre la mesa.'};
            app.PLAYButton_4.Text = '';
            app.PAUSEButton_4 = uibutton(app.SIMULACIONPanel_2, 'push');
            app.PAUSEButton_4.ButtonPushedFcn = createCallbackFcn(app, @pauseSimulacion, true);
            app.PAUSEButton_4.Icon = '80c52538116b504f0d45858a707d0c4f-bot--n-de-pausa-plana-by-vexels.png';
            app.PAUSEButton_4.Text = '';
            app.STOPButton_5 = uibutton(app.SIMULACIONPanel_2, 'push');
            app.STOPButton_5.ButtonPushedFcn = createCallbackFcn(app, @stopSimulacion, true);
            app.STOPButton_5.Icon = 'stop-button-512.png';
            app.STOPButton_5.Text = '';
            app.Slider_2 = uislider(app.SIMULACIONPanel_2);
            app.Slider_2.Limits = [0 510];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.FotogramasSliderLabel_2 = uilabel(app.SIMULACIONPanel_2);
            app.FotogramasSliderLabel_2.Text = 'Nº Fotograma';
            app.ANGULOSPanel_2 = uipanel(app.UIFigure);
            app.ANGULOSPanel_2.Title = 'ÁNGULOS DE LAS ARTICULACIONES';
            app.UIAxes2_2 = uiaxes(app.ANGULOSPanel_2);
            title(app.UIAxes2_2, 'Hombro')
            xlabel(app.UIAxes2_2, 'Nº Fotograma')
            ylabel(app.UIAxes2_2, 'Ángulos (º)')
            app.UIAxes3_2 = uiaxes(app.ANGULOSPanel_2);
            title(app.UIAxes3_2, 'Codo')
            xlabel(app.UIAxes3_2, 'Nº Fotograma')
            ylabel(app.UIAxes3_2, 'Ángulos (º)')
            app.GRAFICARButton_4 = uibutton(app.ANGULOSPanel_2, 'push');
            app.GRAFICARButton_4.ButtonPushedFcn = createCallbackFcn(app, @graficarAngMarcadores, true);
            app.GRAFICARButton_4.Tooltip = {'Grafica los ángulos generados por las articulaciones en un vídeo. Darle sólo cuando haya detectado los marcadores en los dos planos de grabación.'};
            app.GRAFICARButton_4.Text = 'GRAFICAR';
            app.NombredearchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.NombredearchivoEditField = uieditfield(app.UIFigure, 'text');
            app.NombredearchivoEditField.Tooltip = {'Aquí aparecerá el nombre del archivo de vídeo que ha cargado.'};
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.PlanoSagitalButton = uiradiobutton(app.ButtonGroup_2);
            app.PlanoSagitalButton.Text = ' Plano Sagital';
            app.PlanoSagitalButton.Value = true;
            app.PlanoFrontalButton = uiradiobutton(app.ButtonGroup_2);
            app.PlanoFrontalButton.Text = ' Plano Frontal';
            app.seleccionarVideo = uibutton(app.UIFigure, 'push');
            app.seleccionarVideo.ButtonPushedFcn = createCallbackFcn(app, @seleccionarvideo, true);
            app.seleccionarVideo.Icon = 'video.png';
            app.seleccionarVideo.Visible = 'off';
            app.seleccionarVideo.Tooltip = {'Cargue un vídeo.'};
            app.seleccionarVideo.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end