classdef VentanaInforme16NOV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        HOMBROPanel                 matlab.ui.container.Panel
        YAWPanel                    matlab.ui.container.Panel
        ngulomximoEditFieldLabel    matlab.ui.control.Label
        ngulomximoEditField         matlab.ui.control.NumericEditField
        ngulomnimoEditFieldLabel    matlab.ui.control.Label
        ngulomnimoEditField         matlab.ui.control.NumericEditField
        PITCHPanel                  matlab.ui.container.Panel
        ngulomximoEditField_2Label  matlab.ui.control.Label
        ngulomximoEditField_2       matlab.ui.control.NumericEditField
        ngulomnimoEditField_2Label  matlab.ui.control.Label
        ngulomnimoEditField_2       matlab.ui.control.NumericEditField
        ROLLPanel                   matlab.ui.container.Panel
        ngulomximoEditField_3Label  matlab.ui.control.Label
        ngulomximoEditField_3       matlab.ui.control.NumericEditField
        ngulomnimoEditField_3Label  matlab.ui.control.Label
        ngulomnimoEditField_3       matlab.ui.control.NumericEditField
        CODOPanel                   matlab.ui.container.Panel
        YAWPanel_2                  matlab.ui.container.Panel
        ngulomximoEditField_4Label  matlab.ui.control.Label
        ngulomximoEditField_4       matlab.ui.control.NumericEditField
        ngulomnimoEditField_4Label  matlab.ui.control.Label
        ngulomnimoEditField_4       matlab.ui.control.NumericEditField
        ROLLPanel_2                 matlab.ui.container.Panel
        ngulomximoEditField_5Label  matlab.ui.control.Label
        ngulomximoEditField_5       matlab.ui.control.NumericEditField
        ngulomnimoEditField_5Label  matlab.ui.control.Label
        ngulomnimoEditField_5       matlab.ui.control.NumericEditField
        UIAxes1                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        USUARIOPanel                matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        NOMBREEditFieldLabel        matlab.ui.control.Label
        NOMBREEditField             matlab.ui.control.EditField
        EDADEditFieldLabel          matlab.ui.control.Label
        EDADEditField               matlab.ui.control.EditField
        NGULOSMXIMOSYMNIMOSDELHOMBROPanel  matlab.ui.container.Panel
        NGULOSMXIMOSYMNIMOSDELHOMBROPanel_2  matlab.ui.container.Panel
        GRFICADELOSNGULOSDELCODOYDELHOMBROPanel  matlab.ui.container.Panel
        SIMULACINDELAEXTREMIDADSUPERIORPanel  matlab.ui.container.Panel
        Button                      matlab.ui.control.Button
        FECHADatePickerLabel        matlab.ui.control.Label
        FECHADatePicker             matlab.ui.control.DatePicker
        SlideSliderLabel            matlab.ui.control.Label
        SlideSlider                 matlab.ui.control.Slider
        Button_2                    matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
    end

  properties (Access = public)
    %Paciente=struct;
    YawHombro;
    PitchHombro;
    RollHombro;
    YawCodo;
    RollCodo;
    xq=0:1:100; 
    val;
        %Prismas de mano, antebrazo y brazo
       wrist= [15    10  -110;15    20  -110;25    20  -110;25    10  -110;10    10  -130;10    20  -130;30    20  -130;30    10  -130];
       elbow=[10    10   -60;10    20   -60; 30    20   -60;30    10   -60; 15    10  -110;15    20  -110;25    20  -110;25    10  -110];
       shoulder=[ 5   5  -10;5   25   -10;  35    25   -10;35     5   -10;10    10   -60;10    20   -60;30    20   -60;30    10   -60];
    valslidec;
    end 
    
    methods (Access = public)
        

        function func(app,nombre,edad,YawHombro,PitchHombro,RollHombro,YawCodo,RollCodo,valslidec)%, angleminYC,angleminPC,angleminRC)
            %Los parámetros de entrada serán establecidos como valores de
            %las casillas (nombre, ángulomáx..etc)
            
            %DATOS PERSONALES
            app.NOMBREEditField.Value=nombre;  
            app.EDADEditField.Value=edad;
             %PANEL DEL HOMBRO
            app.ngulomximoEditField.Value=max(max(YawHombro));%el 1º valor es el máximo 
            app.ngulomnimoEditField.Value=min(min(YawHombro));%el 2º valor es el mínimo
            app.ngulomximoEditField_2.Value=max(max(PitchHombro));
            app.ngulomnimoEditField_2.Value=min(min(PitchHombro));
            app.ngulomximoEditField_3.Value=max(max(RollHombro));
            app.ngulomnimoEditField_3.Value=min(min(RollHombro));
            %PANEL DEL CODO
            app.ngulomximoEditField_4.Value=max(max(YawCodo));
            app.ngulomnimoEditField_4.Value=min(min(YawCodo));
            app.ngulomximoEditField_5.Value=max(max(RollCodo));
            app.ngulomnimoEditField_5.Value=min(min(RollCodo));    
            
            %Por lo que debemos tener cuidado a la hora de llamar la
            %función EN EL ORDEN CORRECTO DE PARÁMETROS!!!!
            
            %Los parámtros de entradas son unos para los cuadros de textos
            %y otros para hacerlos globales en esta ventana y poder
            %graficarlos en estos nuevos uiaxes:
            app.YawHombro=YawHombro;
            app.PitchHombro=PitchHombro;
            app.RollHombro=RollHombro;
            app.YawCodo=YawCodo;
            app.RollCodo=RollCodo;
            app.valslidec=valslidec;%Valor del slider actual de la ventana ppal(está en decimales)antes de usarlo usar round().
        end
        function graficaInforme(app)
           %ÁNGULOS CODO
                %Grado de libertad yaw
                plot(app.UIAxes2,app.xq,app.YawCodo,'b-')
                hold (app.UIAxes2, 'on')
                %Grado de libertad roll
                plot(app.UIAxes2,app.xq, app.RollCodo,'r-')
                hold (app.UIAxes2, 'off')

            %ÁNGULOS HOMBRO
                %Grado de libertad pitch   
                plot(app.UIAxes1,app.xq,app.PitchHombro,'b-')
                hold (app.UIAxes1, 'on')
                %Grado de libertad yaw
                plot(app.UIAxes1,app.xq,app.YawHombro,'r-')
                %Grado de libertad roll
                plot(app.UIAxes1,app.xq,app.RollHombro,'g-')
                hold (app.UIAxes1, 'off')         
        end
 
        function  Paciente=func2(app)
        %Función que guardará los datos de la ventana informe biomecánico deuvolviendo
        % a un paciente como un struct(conjunto de datos) de (Nombre,Fecha,Hombro,Codo)
        %(para posteriormente guardarlo en un archivo.mat).
            Hombro = struct('YawHombro',app.YawHombro,'PitchHombro',app.PitchHombro,'RollHombro',app.RollHombro);
            Codo = struct('YawCodo',app.YawCodo,'RollCodo',app.RollCodo);
          
            Paciente=struct('Nombre', app.NOMBREEditField.Value,'Edad',app.EDADEditField.Value,'Fecha',app.FECHADatePicker.Value,'Hombro', Hombro, 'Codo', Codo,'ValorSlider',app.valslidec);   
        end
        
        
        function aceptaFecha(app,Fecha)
       %Función que recibe una fecha de tipo datatime y la coloca en la casilla correspondte.
       %La usamos en la ventana AbrirInforme para colocarlo en esta ventana
       %cuando la abra.
        app.FECHADatePicker.Value=Fecha; 
        end
        
        function modelRotated1 = applyRotation1(~,model,punto_giro,yaw,pitch,roll) 
                   
    matrixYaw = [cosd(yaw),-sind(yaw),0;sind(yaw),cosd(yaw),0;0,0,1];%eje Z 
    matrixPitch = [cosd(pitch),0,sind(pitch);0,1,0;-sind(pitch),0,cosd(pitch)];%eje Y
    matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);0,sind(roll),cosd(roll)];%eje X
    
    R = matrixYaw*matrixPitch*matrixRoll;
    
    modelRotated1 = (model-repmat(punto_giro,size(model,1),1))*R;%lo TRAIGO a mi nuevo (0,0,0)
  
    modelRotated1 = modelRotated1 + repmat(punto_giro,size(model,1),1);%lo DEVUELVO al espacio donde se encontraba
 
               end%function 
        
    
        function dibujaENinforme(app,j)      
%**
%1)Rotacion de la muñeca 
%**
v3= applyRotation1(app,app.wrist,sum(app.wrist(1:4,:))/4,0,0,0); 
%**
%2)Rotacion del codo
%**
v2= applyRotation1(app,app.elbow,sum(app.elbow(1:4,:))/4,app.YawCodo(j),0,app.RollCodo(j));
v3= applyRotation1(app,v3,sum(app.elbow(1:4,:))/4,app.YawCodo(j),0,app.RollCodo(j));%le digo súbete que no se quede ahí abajo. 
%**
%3)Rotacion del hombro
%**
v1= applyRotation1(app,app.shoulder,sum(app.shoulder(2:3,:))/2,app.YawHombro(j),app.PitchHombro(j),app.RollHombro(j));%aplico giro al hombr0
v2= applyRotation1(app,v2,sum(app.shoulder(2:3,:))/2,app.YawHombro(j),app.PitchHombro(j),app.RollHombro(j));
v3= applyRotation1(app,v3,sum(app.shoulder(2:3,:))/2,app.YawHombro(j),app.PitchHombro(j),app.RollHombro(j));

%____________________DIBUJA BRAZO_____________________     
f = [1 2 3 4; 5 6 7 8; 1 2 6 5;2 3 7 6;3 4 8 7; 4 1 5 8];

rotate3d(app.UIAxes,'on');

patch(app.UIAxes,'vertices',v1,'faces',f,'FaceVertexCData',10, 'FaceColor', 'flat')
hold(app.UIAxes,'on');
patch(app.UIAxes,'vertices',v2,'faces',f, 'FaceVertexCData', 70, 'FaceColor', 'flat')
patch(app.UIAxes,'vertices',v3,'faces',f, 'FaceVertexCData', 140, 'FaceColor', 'flat')
%****************************
%DIBUJAR ESFERAS
%***************************
%ESFERAS
[X,Y,Z] = sphere;
%Multiplicamos por 10 para que se vean mejor
X=X*10;
Y=Y*10;
Z=Z*10;

%hold(app.UIAxes1,'on');%Necesario, sino brazo y esferas no se dibujan en el mismo tiempo y lugar
surf(app.UIAxes,X+sum(v1(1:4,1)/4),Y+sum(v1(1:4,2)/4),Z+sum(v1(1:4,3)/4));
surf(app.UIAxes,X+sum(v2(1:4,1)/4),Y+sum(v2(1:4,2)/4),Z+sum(v2(1:4,3)/4));
surf(app.UIAxes,X+sum(v3(1:4,1)/4),Y+sum(v3(1:4,2)/4),Z+sum(v3(1:4,3)/4));
hold(app.UIAxes,'off');
drawnow limitrate;
pause(0.1);
        end
        
        
        function setSlider(app,index)
            %Ajusta el indicador del slider en el index que se le marque.
            %Se usa para crear y abrir informe al tener que mostrar la
            %última posicion en la que se quedó antes de que el usuario
            %diera a crear informe.
          app.SlideSlider.Value=index;  
        end
            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
      name = 'Nombre.mat';
             %Recopila todos los datos de la ventana informe.
             Paciente=func2(app);
             [file_name,path] = uiputfile(name);%Crear un archivo
 if ischar(file_name)%Si el nombre tecleado es String
 %if isstring(file_name)
    %save(fullfile(path,file_name),'app', "Paciente")
    save(fullfile(path,file_name),'app', "Paciente")
  %Guárdame un archivo, con este nombre y path, con la app y el paciente   
 end
 
        end

        % Value changed function: SlideSlider
        function SlideSliderValueChanged(app, event)
            app.val = app.SlideSlider.Value;
               I=round(app.val);%Necesario ya que nuestro pulso no es perfecto al elegir un valor entero.   
             
               if I ~=0% si el valor es distinto de cero 
                     cla(app.UIAxes);%Borramos la figura que está en ese momento 
                dibujaENinforme(app,I);%Y dibujamos la nueva solcitada.
%Sugerencia : Como tenemos 101 muestas podemos sumar +1 a la i .               
               end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.HOMBROPanel = uipanel(app.UIFigure);
            app.HOMBROPanel.Title = 'HOMBRO';
            app.YAWPanel = uipanel(app.HOMBROPanel);
            app.YAWPanel.Title = 'YAW';
            app.ngulomximoEditFieldLabel = uilabel(app.YAWPanel);
            app.ngulomximoEditFieldLabel.Text = 'Ángulo máximo';
            app.ngulomximoEditField = uieditfield(app.YAWPanel, 'numeric');
            app.ngulomnimoEditFieldLabel = uilabel(app.YAWPanel);
            app.ngulomnimoEditFieldLabel.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField = uieditfield(app.YAWPanel, 'numeric');
            app.PITCHPanel = uipanel(app.HOMBROPanel);
            app.PITCHPanel.Title = 'PITCH';
            app.ngulomximoEditField_2Label = uilabel(app.PITCHPanel);
            app.ngulomximoEditField_2Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_2 = uieditfield(app.PITCHPanel, 'numeric');
            app.ngulomnimoEditField_2Label = uilabel(app.PITCHPanel);
            app.ngulomnimoEditField_2Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_2 = uieditfield(app.PITCHPanel, 'numeric');
            app.ROLLPanel = uipanel(app.HOMBROPanel);
            app.ROLLPanel.Title = 'ROLL';
            app.ngulomximoEditField_3Label = uilabel(app.ROLLPanel);
            app.ngulomximoEditField_3Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_3 = uieditfield(app.ROLLPanel, 'numeric');
            app.ngulomnimoEditField_3Label = uilabel(app.ROLLPanel);
            app.ngulomnimoEditField_3Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_3 = uieditfield(app.ROLLPanel, 'numeric');
            app.CODOPanel = uipanel(app.UIFigure);
            app.CODOPanel.Title = 'CODO';
            app.YAWPanel_2 = uipanel(app.CODOPanel);
            app.YAWPanel_2.Title = 'YAW';
            app.ngulomximoEditField_4Label = uilabel(app.YAWPanel_2);
            app.ngulomximoEditField_4Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_4 = uieditfield(app.YAWPanel_2, 'numeric');
            app.ngulomnimoEditField_4Label = uilabel(app.YAWPanel_2);
            app.ngulomnimoEditField_4Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_4 = uieditfield(app.YAWPanel_2, 'numeric');
            app.ROLLPanel_2 = uipanel(app.CODOPanel);
            app.ROLLPanel_2.Title = 'ROLL';
            app.ngulomximoEditField_5Label = uilabel(app.ROLLPanel_2);
            app.ngulomximoEditField_5Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_5 = uieditfield(app.ROLLPanel_2, 'numeric');
            app.ngulomnimoEditField_5Label = uilabel(app.ROLLPanel_2);
            app.ngulomnimoEditField_5Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_5 = uieditfield(app.ROLLPanel_2, 'numeric');
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'HOMBRO')
            xlabel(app.UIAxes1, '%Ciclo')
            ylabel(app.UIAxes1, 'Grados')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-20 60];
            app.UIAxes.YLim = [-100 40];
            app.UIAxes.ZLim = [-100 100];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CODO')
            xlabel(app.UIAxes2, '%Ciclo')
            ylabel(app.UIAxes2, 'Grados')
            app.USUARIOPanel = uipanel(app.UIFigure);
            app.USUARIOPanel.Title = 'USUARIO';
            app.Image = uiimage(app.USUARIOPanel);
            app.Image.ImageSource = '538846-200.png';
            app.NOMBREEditFieldLabel = uilabel(app.USUARIOPanel);
            app.NOMBREEditFieldLabel.Text = '  NOMBRE  ';
            app.NOMBREEditField = uieditfield(app.USUARIOPanel, 'text');
            app.EDADEditFieldLabel = uilabel(app.USUARIOPanel);
            app.EDADEditFieldLabel.Text = '    EDAD    ';
            app.EDADEditField = uieditfield(app.USUARIOPanel, 'text');
            app.NGULOSMXIMOSYMNIMOSDELHOMBROPanel = uipanel(app.UIFigure);
            app.NGULOSMXIMOSYMNIMOSDELHOMBROPanel.Title = 'ÁNGULOS MÁXIMOS Y MÍNIMOS DEL HOMBRO';
            app.NGULOSMXIMOSYMNIMOSDELHOMBROPanel_2 = uipanel(app.UIFigure);
            app.NGULOSMXIMOSYMNIMOSDELHOMBROPanel_2.Title = 'ÁNGULOS MÁXIMOS Y MÍNIMOS DEL HOMBRO';
            app.GRFICADELOSNGULOSDELCODOYDELHOMBROPanel = uipanel(app.UIFigure);
            app.GRFICADELOSNGULOSDELCODOYDELHOMBROPanel.Title = 'GRÁFICA DE LOS ÁNGULOS DEL CODO Y DEL HOMBRO';
            app.SIMULACINDELAEXTREMIDADSUPERIORPanel = uipanel(app.UIFigure);
            app.SIMULACINDELAEXTREMIDADSUPERIORPanel.Title = 'SIMULACIÓN DE LA EXTREMIDAD SUPERIOR';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'Guardarinforme.png';
            app.Button.Text = '';
            app.FECHADatePickerLabel = uilabel(app.UIFigure);
            app.FECHADatePickerLabel.Text = 'FECHA';
            app.FECHADatePicker = uidatepicker(app.UIFigure);
            app.SlideSliderLabel = uilabel(app.UIFigure);
            app.SlideSliderLabel.Text = 'Slide';
            app.SlideSlider = uislider(app.UIFigure);
            app.SlideSlider.ValueChangedFcn = createCallbackFcn(app, @SlideSliderValueChanged, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Icono provisional.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaInforme16NOV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end