classdef VentanaInformeMarcadores < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel  matlab.ui.container.Panel
        FlexoextensinhombroPanel_3    matlab.ui.container.Panel
        ngulomximoEditFieldLabel      matlab.ui.control.Label
        ngulomximoEditField           matlab.ui.control.NumericEditField
        ngulomnimoEditFieldLabel      matlab.ui.control.Label
        ngulomnimoEditField           matlab.ui.control.NumericEditField
        AbduccinaduccinhombroPanel    matlab.ui.container.Panel
        ngulomximoEditField_2Label    matlab.ui.control.Label
        ngulomximoEditField_2         matlab.ui.control.NumericEditField
        ngulomnimoEditField_2Label    matlab.ui.control.Label
        ngulomnimoEditField_2         matlab.ui.control.NumericEditField
        PronosupinacincodoPanel       matlab.ui.container.Panel
        ngulomximoEditField_4Label    matlab.ui.control.Label
        ngulomximoEditField_4         matlab.ui.control.NumericEditField
        ngulomnimoEditField_4Label    matlab.ui.control.Label
        ngulomnimoEditField_4         matlab.ui.control.NumericEditField
        FlexoextensincodoPanel        matlab.ui.container.Panel
        ngulomximoEditField_5Label    matlab.ui.control.Label
        ngulomximoEditField_5         matlab.ui.control.NumericEditField
        ngulomnimoEditField_5Label    matlab.ui.control.Label
        ngulomnimoEditField_5         matlab.ui.control.NumericEditField
        UIAxes1                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        USUARIOPanel                  matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        NOMBREEditFieldLabel          matlab.ui.control.Label
        NOMBREEditField               matlab.ui.control.EditField
        EDADEditFieldLabel            matlab.ui.control.Label
        EDADEditField                 matlab.ui.control.EditField
        PropuestademejoraEditFieldLabel  matlab.ui.control.Label
        PropuestademejoraEditField    matlab.ui.control.EditField
        TiemporealdeejecucindelaaccinLabel  matlab.ui.control.Label
        TiemporealdeejecucindelaaccinEditField  matlab.ui.control.EditField
        GUARDARINFORMEButton          matlab.ui.control.Button
        TiempoidealdeejecuccindelaaccinEditFieldLabel  matlab.ui.control.Label
        TiempoidealdeejecuccindelaaccinEditField  matlab.ui.control.EditField
        GRFICADELOSNGULOSDELCODOYDELHOMBROPanel  matlab.ui.container.Panel
        FECHADatePickerLabel          matlab.ui.control.Label
        FECHADatePicker               matlab.ui.control.DatePicker
        Button_2                      matlab.ui.control.Button
        Image2                        matlab.ui.control.Image
        NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel  matlab.ui.container.Panel
        FlexoetensinhombroPanel       matlab.ui.container.Panel
        ngulomximoEditField_6Label    matlab.ui.control.Label
        ngulomximoEditField_6         matlab.ui.control.NumericEditField
        ngulomnimoEditField_6Label    matlab.ui.control.Label
        ngulomnimoEditField_6         matlab.ui.control.NumericEditField
        AbduccinaduccinhombroPanel_2  matlab.ui.container.Panel
        ngulomximoEditField_7Label    matlab.ui.control.Label
        ngulomximoEditField_7         matlab.ui.control.NumericEditField
        ngulomnimoEditField_7Label    matlab.ui.control.Label
        ngulomnimoEditField_7         matlab.ui.control.NumericEditField
        PronosupinacincodoPanel_2     matlab.ui.container.Panel
        ngulomximoEditField_9Label    matlab.ui.control.Label
        ngulomximoEditField_9         matlab.ui.control.NumericEditField
        ngulomnimoEditField_9Label    matlab.ui.control.Label
        ngulomnimoEditField_9         matlab.ui.control.NumericEditField
        FlexoextensincodoPanel_2      matlab.ui.container.Panel
        ngulomximoEditField_10Label   matlab.ui.control.Label
        ngulomximoEditField_10        matlab.ui.control.NumericEditField
        ngulomnimoEditField_10Label   matlab.ui.control.Label
        ngulomnimoEditField_10        matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        Slider                        matlab.ui.control.Slider
        FotogramasSliderLabel         matlab.ui.control.Label
    end

    properties (Access = public)
        %Paciente=struct;
        YawHombroIdeal;
        PitchHombroIdeal;
        RollCodoIdeal;
        PitchCodoIdeal;
        YawHombro;
        PitchHombro;
        RollCodo;
        PitchCodo;
        valslidec;
        x=0:63.75:510;
        xq=0:1:510;
        
        %Ángulos de codo
        ACy=[ 90 90 90 90 0 90 90 90 90];%Pronosupinacion del codo
        ACr=[60    10     0     0     0     0     0    10    60];%Flexoextension del codo
        
        %Ángulos de hombro
        AHp=[ 0   -10   -10     5     5     5   -10   -10     0];%Abduccion-aduccion del hombro
        AHr=[30    80    90    90    90    90    90    80    30];%Flexoextension del hombro
        
        val;
        
        
        %Prismas de mano, antebrazo y brazo
        wrist= [15    10  -110;15    20  -110;25    20  -110;25    10  -110;10    10  -130;10    20  -130;30    20  -130;30    10  -130];
        elbow=[10    10   -60;10    20   -60; 30    20   -60;30    10   -60; 15    10  -110;15    20  -110;25    20  -110;25    10  -110];
        shoulder=[ 5   5  -10;5   25   -10;  35    25   -10;35     5   -10;10    10   -60;10    20   -60;30    20   -60;30    10   -60]
             
    end
    
    methods (Access = public)
        
        
        function func(app,nombre,edad,PitchHombroIdeal,YawHombroIdeal,RollCodoIdeal, PitchCodoIdeal,PitchHombro,YawHombro,RollCodo,PitchCodo,valslidec)%, angleminYC,angleminPC,angleminRC)
            %Los parámetros de entrada serán establecidos como valores de
            %las casillas (nombre, ángulomáx..etc)
            
            %DATOS PERSONALES
            app.NOMBREEditField.Value=nombre;
            app.EDADEditField.Value=edad;
            %PANEL DEL HOMBRO ANGULOS IDEALES
            app.ngulomximoEditField.Value=max(max(PitchHombroIdeal));%el 1º valor es el máximo
            app.ngulomnimoEditField.Value=min(min(PitchHombroIdeal));%el 2º valor es el mínimo
            app.ngulomximoEditField_2.Value=max(max(YawHombroIdeal));
            app.ngulomnimoEditField_2.Value=min(min(YawHombroIdeal));
            %PANEL DEL CODO ANGULOS IDEALES
            app.ngulomximoEditField_4.Value=max(max(RollCodoIdeal));
            app.ngulomnimoEditField_4.Value=min(min(RollCodoIdeal));
            app.ngulomximoEditField_5.Value=max(max(PitchCodoIdeal));
            app.ngulomnimoEditField_5.Value=min(min(PitchCodoIdeal));
            
            %PANEL DEL HOMBRO ANGULOS REALES
            app.ngulomximoEditField_6.Value=max(max(PitchHombro));%el 1º valor es el máximo
            app.ngulomnimoEditField_6.Value=min(min(PitchHombro));%el 2º valor es el mínimo
            app.ngulomximoEditField_7.Value=max(max(YawHombro));
            app.ngulomnimoEditField_7.Value=min(min(YawHombro));
            %PANEL DEL CODO ANGULOS IDEALES
            app.ngulomximoEditField_9.Value=max(max(RollCodo));
            app.ngulomnimoEditField_9.Value=min(min(RollCodo));
            app.ngulomximoEditField_10.Value=max(max(PitchCodo));
            app.ngulomnimoEditField_10.Value=min(min(PitchCodo));
            %Por lo que debemos tener cuidado a la hora de llamar la
            %función EN EL ORDEN CORRECTO DE PARÁMETROS!!!!
            
            %Los parámtros de entradas son unos para los cuadros de textos
            %y otros para hacerlos globales en esta ventana y poder
            %graficarlos en estos nuevos uiaxes:
            app.YawHombroIdeal=YawHombroIdeal;
            app.PitchHombroIdeal=PitchHombroIdeal;
            app.RollCodoIdeal=RollCodoIdeal;
            app.PitchCodoIdeal=PitchCodoIdeal;
            app.PitchHombro=PitchHombro;
            app.YawHombro=YawHombro;
            app.RollCodo=RollCodo;
            app.PitchCodo=PitchCodo;
            app.valslidec=valslidec;
            
        end
        
        
        function graficaMarcadores(app)
            
            %ÁNGULOS CODO
            %Grado de libertad yaw
            plot(app.UIAxes2, app.PitchCodo,'b')
            hold (app.UIAxes2, 'on')
            plot(app.UIAxes2,app.xq,app.PitchCodoIdeal,'b--')
            
            %Grado de libertad roll
            angCodoRollFinal = app.RollCodo(1:510);
            plot(app.UIAxes2,angCodoRollFinal,'r')
            plot(app.UIAxes2,app.xq,app.RollCodoIdeal,'r--')
            legend(app.UIAxes2,'Flexoextensión','Flexoextensión ideal','Pronosupinación','Pronosupinación ideal','Location','best');
            hold (app.UIAxes2, 'off')
            
            %ÁNGULOS HOMBRO
            %Grado de libertad pitch
            plot(app.UIAxes1,app.PitchHombro,'b')
            hold (app.UIAxes1, 'on')
            plot(app.UIAxes1,app.xq,app.PitchHombroIdeal,'b--')
            
            %Grado de libertad yaw
            plot(app.UIAxes1,app.YawHombro,'r')
            plot(app.UIAxes1,app.xq,app.YawHombroIdeal,'r--')
            legend(app.UIAxes1,'Flexoextensión','Flexoextensión ideal','Abducción-Aducción','Abducción-Aducción ideal','Location','best');
            hold (app.UIAxes1, 'off')
        end
        
        
        function  Paciente=func2(app)
            %Función que guardará los datos de la ventana informe biomecánico deuvolviendo
            % a un paciente como un struct(conjunto de datos) de (Nombre,Fecha,Hombro,Codo)
            %(para posteriormente guardarlo en un archivo.mat).
            Hombro = struct('PitchHombroIdeal',app.PitchHombroIdeal,'YawHombroIdeal',app.YawHombroIdeal,'PitchHombro',app.PitchHombro,'YawHombro',app.YawHombro);
            
            Codo = struct('RollCodoIdeal',app.RollCodoIdeal,'PitchCodoIdeal',app.PitchCodoIdeal,'RollCodo',app.RollCodo,'PitchCodo',app.PitchCodo);
            
            Paciente=struct('Nombre', app.NOMBREEditField.Value,'Edad',app.EDADEditField.Value,'Fecha',app.FECHADatePicker.Value, 'Observacion' ,app.PropuestademejoraEditField.Value, 'TiempoReal', app.TiemporealdeejecucindelaaccinEditField.Value,'TiempoIdeal', app.TiempoidealdeejecuccindelaaccinEditField.Value,'Hombro', Hombro, 'Codo', Codo,'ValorSlider',app.valslidec);
        end
        
        
        function aceptaFecha(app,Fecha,Observacion,TiempoIdeal,TiempoReal)
            %Función que recibe una fecha de tipo datatime y la coloca en la casilla correspondte.
            %La usamos en la ventana AbrirInforme para colocarlo en esta ventana
            %cuando la abra.
            app.FECHADatePicker.Value=Fecha;
            app.PropuestademejoraEditField.Value=Observacion;
            app.TiempoidealdeejecuccindelaaccinEditField.Value=TiempoIdeal;
            app.TiemporealdeejecucindelaaccinEditField.Value=TiempoReal;
        end
        
        function modelRotated2 = applyRotation2(~,model,punto_giro,yaw,pitch,roll)
            
            matrixYaw = [cosd(yaw),-sind(yaw),0;sind(yaw),cosd(yaw),0;0,0,1];%eje Z
            matrixPitch = [cosd(pitch),0,sind(pitch);0,1,0;-sind(pitch),0,cosd(pitch)];%eje Y
            matrixRoll = [1,0,0;0,cosd(roll),-sind(roll);0,sind(roll),cosd(roll)];%eje X
            
            R = matrixYaw*matrixPitch*matrixRoll;
            
            modelRotated2 = (model-repmat(punto_giro,size(model,1),1))*R;%lo TRAIGO a mi nuevo (0,0,0)
            
            modelRotated2 = modelRotated2 + repmat(punto_giro,size(model,1),1);%lo DEVUELVO al espacio donde se encontraba
            
        end%function
        
        function dibujaMarcadores(app,j)
            
            %****************** APLICO ROTACIONES A CADA ARTICULACIÓN:***************
            ang1 = app.PitchHombro(j); % Flexoextension Hombro
            ang2 = app.YawHombro(j); % Abduccion aduccion Hombro
            ang3 = app.RollCodo(j);  % Pronosupinacion Codo
            ang4 = app.PitchCodo(j); % Flexoextension Codo
            %*************************
            %Ángulos(MUÑECA)
            
            %1)Rotacion de la muñeca
            v3= applyRotation2(app,app.wrist,sum(app.wrist(1:4,:))/4,0,0,0);
            %sum(wrist(1:4,:))/4 = suma los 4 vertices de la primera 4 y lo divide entre 4 para hallar el
            %punto medio que sea el punto de giro para las rotaciones los argumentos de entrada son yaw=0, pitch=0, roll=0;
            %**************************
            
            %**************************
            %Ángulos(CODO)
            %2)Rotacion del codo
            %Hay que hacer v2 pero tambien aplicarselo a v3 para que no se desajuste
            v2= applyRotation2(app,app.elbow,sum(app.elbow(1:4,:))/4,ang3,0,ang4);
            v3= applyRotation2(app,v3,sum(app.elbow(1:4,:))/4,ang3,0,ang4);
            %***************************
            
            %***************************
            %Ángulos(HOMBRO)
            %3)Rotacion del hombro
            v1= applyRotation2(app,app.shoulder,sum(app.shoulder(2:3,:))/2,0,ang2,ang1);%aplico giro al hombr0
            v2= applyRotation2(app,v2,sum(app.shoulder(2:3,:))/2,0,ang2,ang1);
            v3= applyRotation2(app,v3,sum(app.shoulder(2:3,:))/2,0,ang2,ang1);
            %***************************
            
            %****************************** DIBUJAR BRAZO: ***********************
            
            %DIBUJAR PRISMAS
            f = [1 2 3 4; 5 6 7 8; 1 2 6 5;2 3 7 6;3 4 8 7; 4 1 5 8];%Orden de los vértices para formar cada una de las caras del prisma
            
            %MODIFICACIÓN EJES:
            rotate3d(app.UIAxes,'on');
            axis(app.UIAxes,'equal','on');
            
            patch(app.UIAxes,'vertices',v1,'faces',f,'FaceVertexCData',40, 'FaceColor', 'flat')
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'equal','on');
            patch(app.UIAxes,'vertices',v2,'faces',f, 'FaceVertexCData', 60, 'FaceColor', 'flat')
            patch(app.UIAxes,'vertices',v3,'faces',f, 'FaceVertexCData', 140, 'FaceColor', 'flat')
           
            %****************************
            %DIBUJAR ESFERAS
            %***************************
            %ESFERAS
            [X,Y,Z] = sphere;
            %Multiplicamos por 10 para que se vean mejor
            X=X*10;
            Y=Y*10;
            Z=Z*10;
            
            %hold(app.UIAxes1,'on');%Necesario, sino brazo y esferas no se dibujan en el mismo tiempo y lugar
            surf(app.UIAxes,X+sum(v1(1:4,1)/4),Y+sum(v1(1:4,2)/4),Z+sum(v1(1:4,3)/4));
            surf(app.UIAxes,X+sum(v2(1:4,1)/4),Y+sum(v2(1:4,2)/4),Z+sum(v2(1:4,3)/4));
            surf(app.UIAxes,X+sum(v3(1:4,1)/4),Y+sum(v3(1:4,2)/4),Z+sum(v3(1:4,3)/4));
            hold(app.UIAxes,'off');
        end
        
        function setSlider(app,index)
            
            app.Slider.Value=index;
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GUARDARINFORMEButton
        function GUARDARINFORMEButtonPushed(app, event)
            name = 'Nombre.mat';
            %Recopila todos los datos de la ventana informe.
            Paciente=func2(app);
            [file_name,path] = uiputfile(name);%Crear un archivo
            if ischar(file_name)%Si el nombre tecleado es String
                %if isstring(file_name)
                %save(fullfile(path,file_name),'app', "Paciente")
                save(fullfile(path,file_name),'app', "Paciente")
                %Guárdame un archivo, con este nombre y path, con la app y el paciente
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.val = app.Slider.Value;
            I=round(app.val);%Necesario ya que nuestro pulso no es perfecto al elegir un valor entero.
            
            if I ~=0% si el valor es distinto de cero
                cla(app.UIAxes);%Borramos la figura que está en ese momento
                dibujaMarcadores(app,I);%Y dibujamos la nueva solcitada.
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel = uipanel(app.UIFigure);
            app.NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel.Title = 'ÁNGULOS MÁXIMOS Y MÍNIMOS IDEALES DE LAS ARTICULACIONES (º)';
            app.FlexoextensinhombroPanel_3 = uipanel(app.NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel);
            app.FlexoextensinhombroPanel_3.Title = 'Flexoextensión hombro';
            app.ngulomximoEditFieldLabel = uilabel(app.FlexoextensinhombroPanel_3);
            app.ngulomximoEditFieldLabel.Text = 'Ángulo máximo';
            app.ngulomximoEditField = uieditfield(app.FlexoextensinhombroPanel_3, 'numeric');
            app.ngulomximoEditField.Editable = 'off';
            app.ngulomnimoEditFieldLabel = uilabel(app.FlexoextensinhombroPanel_3);
            app.ngulomnimoEditFieldLabel.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField = uieditfield(app.FlexoextensinhombroPanel_3, 'numeric');
            app.ngulomnimoEditField.Editable = 'off';
            app.AbduccinaduccinhombroPanel = uipanel(app.NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel);
            app.AbduccinaduccinhombroPanel.Title = 'Abducción-aducción hombro';
            app.ngulomximoEditField_2Label = uilabel(app.AbduccinaduccinhombroPanel);
            app.ngulomximoEditField_2Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_2 = uieditfield(app.AbduccinaduccinhombroPanel, 'numeric');
            app.ngulomximoEditField_2.Editable = 'off';
            app.ngulomnimoEditField_2Label = uilabel(app.AbduccinaduccinhombroPanel);
            app.ngulomnimoEditField_2Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_2 = uieditfield(app.AbduccinaduccinhombroPanel, 'numeric');
            app.ngulomnimoEditField_2.Editable = 'off';
            app.PronosupinacincodoPanel = uipanel(app.NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel);
            app.PronosupinacincodoPanel.Title = 'Pronosupinación codo';
            app.ngulomximoEditField_4Label = uilabel(app.PronosupinacincodoPanel);
            app.ngulomximoEditField_4Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_4 = uieditfield(app.PronosupinacincodoPanel, 'numeric');
            app.ngulomximoEditField_4.Editable = 'off';
            app.ngulomnimoEditField_4Label = uilabel(app.PronosupinacincodoPanel);
            app.ngulomnimoEditField_4Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_4 = uieditfield(app.PronosupinacincodoPanel, 'numeric');
            app.ngulomnimoEditField_4.Editable = 'off';
            app.FlexoextensincodoPanel = uipanel(app.NGULOSMXIMOSYMNIMOSIDEALESDELASARTICULACIONESPanel);
            app.FlexoextensincodoPanel.Title = 'Flexoextensión codo';
            app.ngulomximoEditField_5Label = uilabel(app.FlexoextensincodoPanel);
            app.ngulomximoEditField_5Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_5 = uieditfield(app.FlexoextensincodoPanel, 'numeric');
            app.ngulomximoEditField_5.Editable = 'off';
            app.ngulomnimoEditField_5Label = uilabel(app.FlexoextensincodoPanel);
            app.ngulomnimoEditField_5Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_5 = uieditfield(app.FlexoextensincodoPanel, 'numeric');
            app.ngulomnimoEditField_5.Editable = 'off';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'HOMBRO')
            xlabel(app.UIAxes1, 'Nº Fotograma')
            ylabel(app.UIAxes1, 'Ángulos (º)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CODO')
            xlabel(app.UIAxes2, 'Nº Fotograma')
            ylabel(app.UIAxes2, 'Ángulos (º)')
            app.USUARIOPanel = uipanel(app.UIFigure);
            app.USUARIOPanel.Title = 'USUARIO';
            app.Image = uiimage(app.USUARIOPanel);
            app.Image.ImageSource = '538846-200.png';
            app.NOMBREEditFieldLabel = uilabel(app.USUARIOPanel);
            app.NOMBREEditFieldLabel.Text = '  NOMBRE  ';
            app.NOMBREEditField = uieditfield(app.USUARIOPanel, 'text');
            app.EDADEditFieldLabel = uilabel(app.USUARIOPanel);
            app.EDADEditFieldLabel.Text = '    EDAD    ';
            app.EDADEditField = uieditfield(app.USUARIOPanel, 'text');
            app.PropuestademejoraEditFieldLabel = uilabel(app.USUARIOPanel);
            app.PropuestademejoraEditFieldLabel.Text = 'Propuesta de mejora';
            app.PropuestademejoraEditField = uieditfield(app.USUARIOPanel, 'text');
            app.TiemporealdeejecucindelaaccinLabel = uilabel(app.USUARIOPanel);
            app.TiemporealdeejecucindelaaccinLabel.Text = {'Tiempo real de'; ' ejecución de la acción '};
            app.TiemporealdeejecucindelaaccinEditField = uieditfield(app.USUARIOPanel, 'text');
            app.TiemporealdeejecucindelaaccinEditField.Editable = 'off';
            app.TiemporealdeejecucindelaaccinEditField.Value = '11 seg';
            app.GUARDARINFORMEButton = uibutton(app.USUARIOPanel, 'push');
            app.GUARDARINFORMEButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARINFORMEButtonPushed, true);
            app.GUARDARINFORMEButton.Text = 'GUARDAR INFORME';
            app.TiempoidealdeejecuccindelaaccinEditFieldLabel = uilabel(app.USUARIOPanel);
            app.TiempoidealdeejecuccindelaaccinEditFieldLabel.Text = {'Tiempo ideal de'; ' ejecucción de la acción'};
            app.TiempoidealdeejecuccindelaaccinEditField = uieditfield(app.USUARIOPanel, 'text');
            app.TiempoidealdeejecuccindelaaccinEditField.Editable = 'off';
            app.TiempoidealdeejecuccindelaaccinEditField.Value = '13 seg';
            app.GRFICADELOSNGULOSDELCODOYDELHOMBROPanel = uipanel(app.UIFigure);
            app.GRFICADELOSNGULOSDELCODOYDELHOMBROPanel.Title = 'GRÁFICA DE LOS ÁNGULOS DEL CODO Y DEL HOMBRO';
            app.FECHADatePickerLabel = uilabel(app.UIFigure);
            app.FECHADatePickerLabel.Text = 'FECHA';
            app.FECHADatePicker = uidatepicker(app.UIFigure);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'Icono provisional.png';
            app.NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel = uipanel(app.UIFigure);
            app.NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel.Title = 'ÁNGULOS MÁXIMOS Y MÍNIMOS REALES DE LAS ARTICULACIONES (º)';
            app.FlexoetensinhombroPanel = uipanel(app.NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel);
            app.FlexoetensinhombroPanel.Title = 'Flexoetensión hombro';
            app.ngulomximoEditField_6Label = uilabel(app.FlexoetensinhombroPanel);
            app.ngulomximoEditField_6Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_6 = uieditfield(app.FlexoetensinhombroPanel, 'numeric');
            app.ngulomximoEditField_6.Editable = 'off';
            app.ngulomnimoEditField_6Label = uilabel(app.FlexoetensinhombroPanel);
            app.ngulomnimoEditField_6Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_6 = uieditfield(app.FlexoetensinhombroPanel, 'numeric');
            app.ngulomnimoEditField_6.Editable = 'off';
            app.AbduccinaduccinhombroPanel_2 = uipanel(app.NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel);
            app.AbduccinaduccinhombroPanel_2.Title = 'Abducción-aducción hombro';
            app.ngulomximoEditField_7Label = uilabel(app.AbduccinaduccinhombroPanel_2);
            app.ngulomximoEditField_7Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_7 = uieditfield(app.AbduccinaduccinhombroPanel_2, 'numeric');
            app.ngulomximoEditField_7.Editable = 'off';
            app.ngulomnimoEditField_7Label = uilabel(app.AbduccinaduccinhombroPanel_2);
            app.ngulomnimoEditField_7Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_7 = uieditfield(app.AbduccinaduccinhombroPanel_2, 'numeric');
            app.ngulomnimoEditField_7.Editable = 'off';
            app.PronosupinacincodoPanel_2 = uipanel(app.NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel);
            app.PronosupinacincodoPanel_2.Title = 'Pronosupinación codo';
            app.ngulomximoEditField_9Label = uilabel(app.PronosupinacincodoPanel_2);
            app.ngulomximoEditField_9Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_9 = uieditfield(app.PronosupinacincodoPanel_2, 'numeric');
            app.ngulomximoEditField_9.Editable = 'off';
            app.ngulomnimoEditField_9Label = uilabel(app.PronosupinacincodoPanel_2);
            app.ngulomnimoEditField_9Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_9 = uieditfield(app.PronosupinacincodoPanel_2, 'numeric');
            app.ngulomnimoEditField_9.Editable = 'off';
            app.FlexoextensincodoPanel_2 = uipanel(app.NGULOSMXIMOSYMNIMOSREALESDELASARTICULACIONESPanel);
            app.FlexoextensincodoPanel_2.Title = 'Flexoextensión codo';
            app.ngulomximoEditField_10Label = uilabel(app.FlexoextensincodoPanel_2);
            app.ngulomximoEditField_10Label.Text = 'Ángulo máximo';
            app.ngulomximoEditField_10 = uieditfield(app.FlexoextensincodoPanel_2, 'numeric');
            app.ngulomximoEditField_10.Editable = 'off';
            app.ngulomnimoEditField_10Label = uilabel(app.FlexoextensincodoPanel_2);
            app.ngulomnimoEditField_10Label.Text = 'Ángulo mínimo';
            app.ngulomnimoEditField_10 = uieditfield(app.FlexoextensincodoPanel_2, 'numeric');
            app.ngulomnimoEditField_10.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-20 60];
            app.UIAxes.YLim = [-100 40];
            app.UIAxes.ZLim = [-100 100];
            app.UIAxes.XTick = [-20 0 20 40 60 80 100];
            app.UIAxes.XTickLabel = {'-20'; '0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxes.YTick = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 510];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.FotogramasSliderLabel = uilabel(app.UIFigure);
            app.FotogramasSliderLabel.Text = 'Nº Fotograma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VentanaInformeMarcadores

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end