classdef procesadoVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NombredearchivoEditFieldLabel  matlab.ui.control.Label
        NombredearchivoEditField       matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        PROCESADODEVDEOPanel           matlab.ui.container.Panel
        VelocidaddereproduccinEditField  matlab.ui.control.NumericEditField
        INICIARDETECCINDEMARCADORESButton  matlab.ui.control.Button
        FotogramasSliderLabel          matlab.ui.control.Label
        FotogramasSlider               matlab.ui.control.Slider
        PLAYButton                     matlab.ui.control.Button
        PAUSEButton                    matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        Label                          matlab.ui.control.Label
        VelocidaddereproduccinLabel    matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PlanoSagitalButton             matlab.ui.control.RadioButton
        PlanoFrontalButton             matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        
        simulat=false;
        v;
        
        centHombroVerde=[];
        centHombroAzul=[];
        centCodo=[];
        centMunecaRojo=[];
        centCadera=[];
        centMunecaAzul=[];
        centPechoAmarillo = [];
        
        CHombro=[];
        CPecho=[];
        CMunecaR=[];
        CMunecaA=[];
        CCadera=[];
        CCodo=[];
        
        angHY=[];
        angHP=[];
        angCP=[];
        angCR=[];
        
    end
    
    methods (Access = public)
        
        % Esta funcion detecta los marcadores y representa su posición
        % sobre un vídeo grabado desde un plano sagital
        function planoSagital(app,i)
            
            cla(app.UIAxes); % Limpiamos la zona de reproducción
            app.FotogramasSlider.Value=i; % El slider irá tomando el valor del fotograma que muestra la reproducción
            
            video=read(app.v,i); % Nos da los fotogramas del video uno a uno
            
            imR=(video(:,:,1)); % Matriz del primer componenete RGB (rojo)
            imG=(video(:,:,2)); % Matriz del segundo componente RGB (verde)
            imB=(video(:,:,3)); % Matriz del tercer componente RGB (azul)
            
            % ============================= DETECCIONES DE MARCADORES =============================
            
            % Para captar el marcador lo haremos por umbralización --> si los pixeles
            % cumplen las condiciones que ponemos se podrán en 1 y sino se pondra en 0
            
            %Imagen binaria de cada marcador
            marcador1 =video(:,:,1).*0; %1º marcador hace referencia al marcador de color verde codo
            marcador1(imR>73 & imR<134 & imG>154 & imG<255 & imB>58 & imB<119)=1;
            
            marcador2= video(:,:,1).*0 ; %2º marcador hace referencia al marcador de color rojo muñeca
            marcador2(imR>120 & imR<255 & imG>3 & imG<58 & imB>6 & imB<60)=1;
            
            marcador3= video(:,:,1).*0;%3º marcador hace referencia al marcador de color azul hombro
            marcador3(imR>0 & imR<40 & imG>35 & imG<73 & imB>76 & imB<145)=1;
            
            marcador4 =video(:,:,1).*0;%4º marcador hace referencia al marcador de color amarillo cadera
            marcador4(imR>140 & imR<255 & imG>100 & imG<155 & imB>0 & imB<50)=1;
            
            % ============================= CÁLCULO DE NUEVAS VARIABLES =============================
            
            % Elimina los objetos cuyo número de píxeles sea <5
            MinPix=5;
            imgFilled1 = bwareaopen (marcador1,MinPix);% siendo 5 el numero de pixeles que quiero eliminar por cada marcador
            imgFilled2 = bwareaopen (marcador2,MinPix);
            imgFilled3 = bwareaopen (marcador3,MinPix);
            imgFilled4 = bwareaopen (marcador4,MinPix);
            
            % Detección de objetos por cada marcador:
            [L1,Ne1] = bwlabel(imgFilled1);
            [L2,Ne2] = bwlabel(imgFilled2);
            [L3,Ne3] = bwlabel(imgFilled3);
            [L4,Ne4] = bwlabel(imgFilled4);
            
            % Calculo de propiedades de los objetos para calcular luego el centroide de cada marcador
            propied1=regionprops(L1);
            propied2=regionprops(L2);
            propied3=regionprops(L3);
            propied4=regionprops(L4);
            
            %Calculo de centroides (Hombro, Codo, Muneca y Pecho)
            if (isempty(propied1)&& size(app.CCodo,1)>0)
                app.centCodo=app.CCodo(size(app.CCodo,1),:);
            else
                app.centCodo=propied1.Centroid;
            end
            app.CCodo=[app.CCodo,app.centCodo];
            
            if (isempty(propied2) && size(app.CMunecaR)>0)
                app.centMunecaRojo= app.CMunecaR(size(app.CMunecaR,1),:);
            else
                app.centMunecaRojo=propied2.Centroid;
            end
            app.CMunecaR=[app.CMunecaR,app.centMunecaRojo];
            
            if (isempty(propied3) && size(app.CHombro)>0)
                app.centHombroAzul= app.CHombro(size(app.CHombro,1),:);
            else
                app.centHombroAzul=propied3.Centroid;
            end
            app.CHombro=[app.CHombro,app.centHombroAzul];
            
            if (isempty(propied4) && size(app.CCadera)>0)
                app.centCadera=app.CCadera(size(app.CCadera,1),:);
            else
                app.centCadera=propied4.Centroid;
            end
            app.CCadera=[app.CCadera,app.centCadera];
            
            % ====================== REPRODUCCIÓN VÍDEO CON POSICION DE MARCADORES ======================
            imshow(video,'Parent', app.UIAxes);
            hold(app.UIAxes,'on');
            if (isempty(propied1)), else
                plot(app.UIAxes,app.centCodo(1,1),app.centCodo(1,2), 'w+');
            end
            if (isempty(propied3)), else
                plot(app.UIAxes,app.centHombroAzul(1,1),app.centHombroAzul(1,2), 'm+');%imprimo un centroide con su cordenada x e y
            end
            if(isempty(propied2)), else
                plot(app.UIAxes,app.centMunecaRojo(1,1),app.centMunecaRojo(1,2), 'w+');
            end
            if (isempty(propied4)), else
                plot(app.UIAxes,app.centCadera(1,1),app.centCadera(1,2), 'w+');
            end
            pause(app.VelocidaddereproduccinEditField.Value); % Velocidad de reproduccion elegida por el usuario
            hold(app.UIAxes,'off');
            drawnow; % Permite ir actualizando la reproduccion sobre la grafica
        end
        
        % Esta funcion detecta los marcadores y representa su posición
        % sobre un vídeo grabado desde un plano frontal
        function planoFrontal(app,i)
            
            cla(app.UIAxes); % Limpiamos la zona de reproducción
            app.FotogramasSlider.Value=i; % El slider irá tomando el valor del fotograma que muestra la reproducción
            
            video=read(app.v,i); % Nos da los fotogramas del video uno a uno
            
            imR=(video(:,:,1)); % Matriz del primer componenete RGB (rojo)
            imG=(video(:,:,2)); % Matriz del segundo componente RGB (verde)
            imB=(video(:,:,3)); % Matriz del tercer componente RGB (azul)
            
            % ============================= DETECCIONES DE MARCADORES =============================
            
            % Para captar el marcador lo haremos por umbralización --> si los pixeles
            % cumplen las condiciones que ponemos se podrán en 1 y sino se pondra en 0
            
            %Imagen binaria de cada marcador
            marcador1 =video(:,:,1).*0; %1º marcador hace referencia al marcador de color verde hombro
            marcador1(imR>110 & imR<134 & imG>210 & imG<255 & imB>90 & imB<130)=1;
            
            marcador2= video(:,:,1).*0 ; %2º marcador hace referencia al marcador de color rojo muñeca
            marcador2(imR>141 & imR<191 & imG>9 & imG<57 & imB>12 & imB<60)=1;
            
            marcador3= video(:,:,1).*0;%3º marcador hace referencia al marcador de color azul muñeca
            marcador3(imR>0 & imR<19 & imG>46 & imG<75 & imB>121 & imB<193)=1;
            
            marcador4 =video(:,:,1).*0;%4º marcador hace referencia al marcador de color amarillo pecho
            marcador4(imR>195 & imR<255 & imG>141 & imG<213 & imB>15 & imB<101)=1;
            
            % ============================= CÁLCULO DE NUEVAS VARIABLES =============================
            
            % Elimina los objetos cuyo número de píxeles sea <5
            MinPix=5;
            imgFilled1 = bwareaopen (marcador1,MinPix);% siendo 5 el numero de pixeles que quiero eliminar por cada marcador
            imgFilled2 = bwareaopen (marcador2,MinPix);
            imgFilled3 = bwareaopen (marcador3,MinPix);
            imgFilled4 = bwareaopen (marcador4,MinPix);
            
            % Detección de objetos por cada marcador:
            [L1,Ne1] = bwlabel(imgFilled1);
            [L2,Ne2] = bwlabel(imgFilled2);
            [L3,Ne3] = bwlabel(imgFilled3);
            [L4,Ne4] = bwlabel(imgFilled4);
            
            % Calculo de propiedades de los objetos para calcular luego el centroide de cada marcador
            propied1=regionprops(L1);
            propied2=regionprops(L2);
            propied3=regionprops(L3);
            propied4=regionprops(L4);
            
            %Calculo de centroides (Hombro, Codo, Muneca y Pecho)
            
            % Si no detecta un marcador, coge el valor del centroide anterior
            if (isempty(propied1) && size(app.CHombro,1)>0)
                app.centHombroVerde=app.CHombro(size(app.CHombro,1),:);
            else
                app.centHombroVerde=propied1.Centroid;
            end
            app.CHombro = [app.CHombro;app.centHombroVerde];
            
            % Si no detecta el marcador rojo, detecta el azul
            % (Está hecho así porque en función de cual detecte será un ángulo u otro
            % de prono-supinación, pues este solo puede ser 0 o 90º en nuestro caso.
            if (isempty(propied3))
                app.centMunecaRojo=propied2.Centroid;
            else
                app.centMunecaAzul=propied3.Centroid;
            end
            app.CMunecaR=[app.CMunecaR;app.centMunecaRojo];
            app.CMunecaA=[app.CMunecaA;app.centMunecaAzul];
            
            if (isempty(propied4) && size(app.CPecho,1)>0)
                app.centPechoAmarillo=app.CPecho(size(app.CPecho,1),:);
            else
                app.centPechoAmarillo=propied4.Centroid;
            end
            app.CPecho=[app.CPecho;app.centPechoAmarillo];
            
            % ====================== REPRODUCCIÓN VÍDEO CON POSICION DE MARCADORES ======================
            imshow(video,'Parent', app.UIAxes);
            hold(app.UIAxes,'on');
            if (isempty(propied1)), else
                plot(app.UIAxes,app.centHombroVerde(1,1),app.centHombroVerde(1,2), 'm+');%imprimo un centroide con su cordenada x e y
            end
            if (isempty(propied3))
                plot(app.UIAxes,app.centMunecaRojo(1,1),app.centMunecaRojo(1,2), 'w+');
            else
                plot(app.UIAxes,app.centMunecaAzul(1,1),app.centMunecaAzul(1,2), 'w+');
            end
            if (isempty(propied4)), else
                plot(app.UIAxes,app.centPechoAmarillo(1,1),app.centPechoAmarillo(1,2), 'k+');
            end
            pause(app.VelocidaddereproduccinEditField.Value); % Velocidad de reproduccion elegida por el usuario
            hold(app.UIAxes,'off');
            drawnow; % Permite ir actualizando la reproduccion sobre la grafica
        end
        
        % Esta función permite la reproducción del vídeo siempre que la
        % variable simulat sea true.
        function simulatTrue(app)
            while(app.simulat)
                for i=1:app.v.numFrames
                    
                    % Comprobamos qué plano de grabación ha sido
                    % seleccionado para poder aplicar una técnica de
                    % detección de marcadores u otra
                    selectedButton = app.ButtonGroup.SelectedObject;
                    
                    switch selectedButton
                        
                        case app.PlanoSagitalButton
                            planoSagital(app,i);
                            
                        case app.PlanoFrontalButton
                            planoFrontal(app,i);
                    end
                    
                    if app.simulat== false
                        break
                    end
                    
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.UIAxes.Visible = 'off';
            
            % CARGAR VIDEO
            [filename pathname] = uigetfile({'*.mp4'}, 'File Selector');
            
            fullpathname = strcat(pathname,filename);
            text = fileread(fullpathname);
            
            app.NombredearchivoEditField.Value = filename;
            app.v = VideoReader(filename);
            
            app.PLAYButton.Enable = 'off';
            app.PAUSEButton.Enable = 'off';
            app.STOPButton.Enable = 'off';
            app.INICIARDETECCINDEMARCADORESButton.Enable = 'off';
            app.VelocidaddereproduccinEditField.Editable = 'off';
            app.FotogramasSlider.Enable = 'off';
            app.FotogramasSlider.Limits = [0,app.v.numFrames];
            
            % Reproducimos el vídeo inicialmente a una velocidad por
            % defecto de 0.05
            while hasFrame(app.v)
                imshow(readFrame(app.v), 'Parent', app.UIAxes);
                pause(app.VelocidaddereproduccinEditField.Value);
                drawnow;
            end
            
            app.Label.Visible = 'off';
            app.VelocidaddereproduccinLabel.Visible = 'on';
            app.Label_2.Visible = 'on';
            app.INICIARDETECCINDEMARCADORESButton.Enable = 'on';
            app.VelocidaddereproduccinEditField.Visible = 'on';
            app.VelocidaddereproduccinEditField.Editable = 'on';
            
            
        end

        % Button pushed function: INICIARDETECCINDEMARCADORESButton
        function detectarMarcadores(app, event)
            
            app.PlanoSagitalButton.Enable = 'on';
            app.PlanoFrontalButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            app.FotogramasSlider.Enable = 'on';
            app.PAUSEButton.Enable = 'off';
            app.STOPButton.Enable = 'off';
            
        end

        % Button pushed function: PLAYButton
        function playPushed(app, event)
            
            app.PAUSEButton.Enable = 'on';
            app.STOPButton.Enable = 'on';
            
            app.simulat=true;
            simulatTrue(app);
            
        end

        % Button pushed function: PAUSEButton
        function pausePushed(app, event)
            
            app.FotogramasSlider.Enable = 'on';
            app.simulat= false;
            
        end

        % Button pushed function: STOPButton
        function stopPushed(app, event)
            
            app.simulat=false;
            cla(app.UIAxes);
            app.FotogramasSlider.Enable = 'on';
            app.FotogramasSlider.Value = 0;
            
        end

        % Value changing function: FotogramasSlider
        function fotogramaSlider(app, event)
            
            changingValue = event.Value; % Obtenemos el valor de la posición del Slider pulsada
            if (changingValue == 0)
                nuevoFotograma = 1;
            else
                nuevoFotograma = round(changingValue,0); % Redondeamos a un número entero
            end
            
            selectedButton = app.ButtonGroup.SelectedObject; % Comprobamos de nuevo qué plano de grabación está seleccionado
            app.simulat = false; % No queremos reproducción automática, sino que muestre el fotograma seleccionado
            
            cla(app.UIAxes); % Limpiamos el gráfico
            
            % En función de qué plano de grabación está seleccionado,
            % ejecutamos una función u otra
            switch selectedButton
                case app.PlanoSagitalButton
                    planoSagital(app,nuevoFotograma);
                case app.PlanoFrontalButton
                    planoFrontal(app,nuevoFotograma);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NombredearchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.NombredearchivoEditField = uieditfield(app.UIFigure, 'text');
            app.NombredearchivoEditField.Tooltip = {'Aquí aparecerá el nombre del archivo de vídeo que ha cargado.'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'IconoPorovisional.jpeg';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = 'IconoPorovisional.jpeg';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = 'IconoPorovisional.jpeg';
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Icono provisional.png';
            app.PROCESADODEVDEOPanel = uipanel(app.UIFigure);
            app.PROCESADODEVDEOPanel.Title = 'PROCESADO DE VÍDEO';
            app.VelocidaddereproduccinEditField = uieditfield(app.PROCESADODEVDEOPanel, 'numeric');
            app.VelocidaddereproduccinEditField.Visible = 'off';
            app.VelocidaddereproduccinEditField.Tooltip = {'Introduzca la velocidad de reproducción del vídeo procesado.'};
            app.VelocidaddereproduccinEditField.Value = 0.005;
            app.INICIARDETECCINDEMARCADORESButton = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.INICIARDETECCINDEMARCADORESButton.ButtonPushedFcn = createCallbackFcn(app, @detectarMarcadores, true);
            app.INICIARDETECCINDEMARCADORESButton.Tooltip = {'Permite el acceso al procesado de vídeo, activando los botones de reproducción, pausa y stop.'};
            app.INICIARDETECCINDEMARCADORESButton.Text = 'INICIAR DETECCIÓN DE MARCADORES';
            app.FotogramasSliderLabel = uilabel(app.PROCESADODEVDEOPanel);
            app.FotogramasSliderLabel.Text = 'Fotogramas';
            app.FotogramasSlider = uislider(app.PROCESADODEVDEOPanel);
            app.FotogramasSlider.ValueChangingFcn = createCallbackFcn(app, @fotogramaSlider, true);
            app.FotogramasSlider.Tooltip = {'Permite seleccionar el fotograma que se desea ver.'};
            app.PLAYButton = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @playPushed, true);
            app.PLAYButton.Icon = 'simbolo-play-png-.png';
            app.PLAYButton.Tooltip = {'Comienza la reproducción del vídeo con la detección de marcadores.'};
            app.PLAYButton.Text = '';
            app.PAUSEButton = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.PAUSEButton.ButtonPushedFcn = createCallbackFcn(app, @pausePushed, true);
            app.PAUSEButton.Icon = '80c52538116b504f0d45858a707d0c4f-bot--n-de-pausa-plana-by-vexels.png';
            app.PAUSEButton.Tooltip = {'Pausa la reproducción del vídeo.'};
            app.PAUSEButton.Text = '';
            app.STOPButton = uibutton(app.PROCESADODEVDEOPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @stopPushed, true);
            app.STOPButton.Icon = 'stop-button-512.png';
            app.STOPButton.Tooltip = {'Para la reproducción y vuelve al inicio.'};
            app.STOPButton.Text = '';
            app.UIAxes = uiaxes(app.PROCESADODEVDEOPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.Label = uilabel(app.PROCESADODEVDEOPanel);
            app.Label.Text = {'Se habilitarán las funciones de detección de marcadores una vez termine la reproducción del vídeo cargado. '; 'Asegúrese de que ha seleccionado el plano de grabación correcto antes de inicializar la detección de marcadores.'};
            app.VelocidaddereproduccinLabel = uilabel(app.PROCESADODEVDEOPanel);
            app.VelocidaddereproduccinLabel.Visible = 'off';
            app.VelocidaddereproduccinLabel.Text = 'Velocidad de reproducción';
            app.Label_2 = uilabel(app.PROCESADODEVDEOPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = 'Elija la velocidad de reproducción del vídeo antes de darle al play.';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Tooltip = {'Seleccione el plano de grabación del vídeo que ha cargado.'};
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.PlanoSagitalButton = uiradiobutton(app.ButtonGroup);
            app.PlanoSagitalButton.Text = ' Plano Sagital';
            app.PlanoSagitalButton.Value = true;
            app.PlanoFrontalButton = uiradiobutton(app.ButtonGroup);
            app.PlanoFrontalButton.Text = ' Plano Frontal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = procesadoVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end